classdef InvestingForRetirement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        YouandyourlifeMenu              matlab.ui.container.Menu
        TestitemMenu                    matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        Toolbar                         matlab.ui.container.Toolbar
        PushTool                        matlab.ui.container.toolbar.PushTool
        PushTool2                       matlab.ui.container.toolbar.PushTool
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        YouandyourlifeTab               matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        MypersonalinfoPanel             matlab.ui.container.Panel
        MygenderisButtonGroup           matlab.ui.container.ButtonGroup
        OtherGenderButton               matlab.ui.control.RadioButton
        MaleButton                      matlab.ui.control.RadioButton
        FemaleButton                    matlab.ui.control.RadioButton
        CountryInputField               matlab.ui.control.EditField
        ThecountryIliveinEditFieldLabel  matlab.ui.control.Label
        AgeInputField                   matlab.ui.control.NumericEditField
        AgelastbirthdayEditFieldLabel   matlab.ui.control.Label
        MyfinancialInfoPanel            matlab.ui.container.Panel
        IwanttostarttheanalysisPanel    matlab.ui.container.Panel
        FromDatePicker_3                matlab.ui.control.DatePicker
        FromDatePicker_3Label           matlab.ui.control.Label
        IwantmylifetimeincometocommencefromPanel  matlab.ui.container.Panel
        FromDatePicker_2                matlab.ui.control.DatePicker
        FromDatePicker_2Label           matlab.ui.control.Label
        IwillcontributeforPanel         matlab.ui.container.Panel
        YearsEditField                  matlab.ui.control.NumericEditField
        YearsEditFieldLabel             matlab.ui.control.Label
        contributionsIncreaseSlider     matlab.ui.control.Slider
        IexpectmycontributionswillincreasebyxpaSliderLabel  matlab.ui.control.Label
        targetContributionsSlider       matlab.ui.control.Slider
        MytargetcontributionskpaTodaysDollarsSliderLabel  matlab.ui.control.Label
        incomeincreaseSlider            matlab.ui.control.Slider
        IexpectmylifetimeincomewillneedtoincreasebyxpaSliderLabel  matlab.ui.control.Label
        incomeFrequencyKnob             matlab.ui.control.DiscreteKnob
        IwanttoreceiveincomeKnobLabel   matlab.ui.control.Label
        investibleAssetsSlider          matlab.ui.control.Slider
        MycurrentinvestibleassetsmSliderLabel  matlab.ui.control.Label
        targetIncomeSlider              matlab.ui.control.Slider
        MytargetIncomekpaLabel          matlab.ui.control.Label
        SampleInterestratesTab          matlab.ui.container.Tab
        RatecurvesfortwocountriesPanel  matlab.ui.container.Panel
        OverlayanotherdateButton        matlab.ui.control.Button
        PlotratesforselectedfileButton  matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        SelectcountriestodisplayPanel   matlab.ui.container.Panel
        Country2DropDown                matlab.ui.control.DropDown
        Country2DropDownLabel           matlab.ui.control.Label
        Country1DropDown                matlab.ui.control.DropDown
        Country1DropDownLabel           matlab.ui.control.Label
        SelectratecurvestoloadPanel     matlab.ui.container.Panel
        SelectadateDatePicker           matlab.ui.control.DatePicker
        SelectadateLabel                matlab.ui.control.Label
        LoadselectedratefileButton      matlab.ui.control.Button
        SelectaratefileButton           matlab.ui.control.Button
        SampleDatesKnob                 matlab.ui.control.DiscreteKnob
        SampleDatesKnobLabel            matlab.ui.control.Label
        MortalityandAnnuitiesTab        matlab.ui.container.Tab
        SampleAnnuityValuePanel         matlab.ui.container.Panel
        CalulateannuityvalueButton      matlab.ui.control.Button
        FixedAnnuityEditField           matlab.ui.control.NumericEditField
        FixedAnnuityEditFieldLabel      matlab.ui.control.Label
        SingleLifeTimeEditField         matlab.ui.control.NumericEditField
        SingleLifeTimeEditFieldLabel    matlab.ui.control.Label
        SampleannuitypaymentcharacteristicsPanel  matlab.ui.container.Panel
        AnnuityIncomeEditField          matlab.ui.control.NumericEditField
        AnnuityIncomeEditFieldLabel     matlab.ui.control.Label
        GuaranteedincreaserateEditField_2  matlab.ui.control.NumericEditField
        GuaranteedincreaserateEditField_2Label  matlab.ui.control.Label
        IncomedeferredyrsEditField_2    matlab.ui.control.NumericEditField
        IncomedeferredyrsEditField_2Label  matlab.ui.control.Label
        AnnuityTermEditField            matlab.ui.control.NumericEditField
        AnnuityTermEditFieldLabel       matlab.ui.control.Label
        AnnuitysensitivityruncontrolPanel  matlab.ui.container.Panel
        PlotannuityvaluesButton         matlab.ui.control.Button
        PopulateAnnuityValuesTableButton  matlab.ui.control.Button
        AnnuityvaluesensitivityparametersPanel  matlab.ui.container.Panel
        AnnuityTypeToDisplay            matlab.ui.control.ListBox
        AnnuityTypeForTableLabel        matlab.ui.control.Label
        XAxisVariableLabel_2            matlab.ui.control.Label
        InputVariablesForTableListBox   matlab.ui.control.ListBox
        InputVariablesForTableListBoxLabel  matlab.ui.control.Label
        LineToPlotListBox               matlab.ui.control.ListBox
        XAxisToPlotListBox              matlab.ui.control.ListBox
        XAxisVariableLabel              matlab.ui.control.Label
        UISampleAnnuityValuesTable      matlab.ui.control.Table
        PlotofannuityvaluesversusmaximumyearsPanel  matlab.ui.container.Panel
        SamplevaluationassumptionsPanel  matlab.ui.container.Panel
        SamplevaluationageEditField     matlab.ui.control.NumericEditField
        SamplevaluationageEditFieldLabel  matlab.ui.control.Label
        MortalityimprovementListBox     matlab.ui.control.ListBox
        MortalityimprovementListBoxLabel  matlab.ui.control.Label
        MortalityTableListBox           matlab.ui.control.ListBox
        MortalityTableListBoxLabel      matlab.ui.control.Label
        SamplevaluationrateEditField    matlab.ui.control.NumericEditField
        SamplevaluationrateEditFieldLabel  matlab.ui.control.Label
        FinancialmarketsTab             matlab.ui.container.Tab
        UITableMarketReferenceSimData   matlab.ui.control.Table
        Plotmarketdata                  matlab.ui.container.Panel
        SPanel                          matlab.ui.container.Panel
        ESGModelListBox                 matlab.ui.control.ListBox
        ESGModelListBoxLabel            matlab.ui.control.Label
        SimulationStartDateDatePicker   matlab.ui.control.DatePicker
        SimulationStartDateDatePickerLabel  matlab.ui.control.Label
        DisplaymarketdataforperiodPanel  matlab.ui.container.Panel
        MarketScenarioFromDatePicker    matlab.ui.control.DatePicker
        FromDatePickerLabel             matlab.ui.control.Label
        MarketScenarioToDatePicker      matlab.ui.control.DatePicker
        ToDatePickerLabel               matlab.ui.control.Label
        DisplaypricesorreturnsButtonGroup  matlab.ui.container.ButtonGroup
        BothButton                      matlab.ui.control.ToggleButton
        ReturnsButton                   matlab.ui.control.ToggleButton
        PricesButton                    matlab.ui.control.ToggleButton
        InterestratestoshowListBox      matlab.ui.control.ListBox
        InterestratestoshowListBoxLabel  matlab.ui.control.Label
        PlotMarketDataButton            matlab.ui.control.Button
        LoadMarketDataButton            matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        LoadallNode                     matlab.ui.container.TreeNode
        InterestratesNode               matlab.ui.container.TreeNode
        EquityindexesNode               matlab.ui.container.TreeNode
        InvestingforretirementincomeTab  matlab.ui.container.Tab
        TradingportoflioinstrumentsPanel  matlab.ui.container.Panel
        AnnuityTypePanel                matlab.ui.container.Panel
        MaxnumberofpaymentsEditField    matlab.ui.control.NumericEditField
        MaxnumberofpaymentsEditFieldLabel  matlab.ui.control.Label
        GuaranteedincreaserateEditField  matlab.ui.control.NumericEditField
        GuaranteedincreaserateEditFieldLabel  matlab.ui.control.Label
        IncomedeferredyrsEditField      matlab.ui.control.NumericEditField
        IncomedeferredyrsEditFieldLabel  matlab.ui.control.Label
        HedgeAnnuityStartDateDatePicker  matlab.ui.control.DatePicker
        StartDateDatePickerLabel        matlab.ui.control.Label
        AnnuityTypeListBox              matlab.ui.control.ListBox
        AnnuityTypeListBoxLabel         matlab.ui.control.Label
        MarketIndexAccountParametersPanel  matlab.ui.container.Panel
        AccountNameEditField            matlab.ui.control.EditField
        AccountNameEditFieldLabel       matlab.ui.control.Label
        UITableMarketIndexAcct          matlab.ui.control.Table
        MainPortfolioTradingStrategyPanel  matlab.ui.container.Panel
        IncomeToAnnuitiseEditField      matlab.ui.control.NumericEditField
        IncometoannuitiseEditFieldLabel  matlab.ui.control.Label
        StrategyListBox                 matlab.ui.control.ListBox
        StrategyListBoxLabel            matlab.ui.control.Label
        SimulationcontrolPanel          matlab.ui.container.Panel
        PlotResultsButton               matlab.ui.control.Button
        RunScenarioButton               matlab.ui.control.Button
        PortfolioevolutionvisualisationPanel  matlab.ui.container.Panel
        InvestmentStrategyDefintiionPanel_3  matlab.ui.container.Panel
        PortfoliostartdateDatePicker    matlab.ui.control.DatePicker
        PortfoliostartdateDatePickerLabel  matlab.ui.control.Label
        InvestmentObjectiveLabel_3      matlab.ui.control.Label
        RiskconstraintsLabel_2          matlab.ui.control.Label
        TargetIncome                    matlab.ui.control.NumericEditField
        InitialPortoflioValueLabel_4    matlab.ui.control.Label
        PlanningHorizon                 matlab.ui.control.NumericEditField
        InitialPortoflioValueLabel_3    matlab.ui.control.Label
        InitialPortfolioValue           matlab.ui.control.NumericEditField
        InitialPortoflioValueLabel      matlab.ui.control.Label
        InvestingforaccumulationTab     matlab.ui.container.Tab
        SimulationParametersPanel_2     matlab.ui.container.Panel
        MortalitytableLabel             matlab.ui.control.Label
        AssetValuationFrequencyLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        InvestmentStatisticsPanel       matlab.ui.container.Panel
        EfficientfrontierPanel          matlab.ui.container.Panel
        InvestmentStrategyDefintiionPanel  matlab.ui.container.Panel
        ProportionofassettoinvestinmarketindexaccountLabel  matlab.ui.control.Label
        RiskconstraintsLabel            matlab.ui.control.Label
        AnnualcontributionLabel         matlab.ui.control.Label
        InitialportfoliovalueLabel      matlab.ui.control.Label
        TypeofannuityLabel              matlab.ui.control.Label
        ProportionofassettoinvestinannuityLabel  matlab.ui.control.Label
        MaximumnumberofpaymentsLabel    matlab.ui.control.Label
        GuaranteedinflationrateLabel    matlab.ui.control.Label
        firstannuitypaymentdeferredbyLabel  matlab.ui.control.Label
        annuitystartdateLabel           matlab.ui.control.Label
        PortfolioTradingStrategyLabel   matlab.ui.control.Label
        AnnuitytobuyLabel               matlab.ui.control.Label
        TradingStrategyLabel            matlab.ui.control.Label
        ExposureLimitsLabel             matlab.ui.control.Label
        TargetWeightsLabel              matlab.ui.control.Label
        InstrumentsLabel                matlab.ui.control.Label
        NameLabel                       matlab.ui.control.Label
        ReferencePortfolioParametersLabel  matlab.ui.control.Label
        AnnuityinstrumentparametersLabel  matlab.ui.control.Label
        PlanningHorizonLabel            matlab.ui.control.Label
        MarketIndexAccountParametersLabel  matlab.ui.control.Label
        AllowableInstrumentsLabel       matlab.ui.control.Label
        InvestmentObjectiveLabel        matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu_2                          matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Person % Description
        MarketData  % Description
        Scenario %Scenario % Description
        %MarketData MarketData
        InputArgs % Description
        GlobalAppArgs
        InvestingForIncomeSimulationResult
        InvestingForIncomePostSimulationScenario
        
        %RatesAnalysisApp  % aProperty to hold the previously deisgned app
        ValuationDate           
        startDateRates
        ScenarioStartDate 
        ScenarioEndDate
        MarketScenarioStartDate
        MarketScenarioEndDate
        MarketSimulationStartDate
        AvailableIndices
        SelectedTickers
        MarketUniverseTickers
        inputFolderRates
        inputFileNameRates
        outputFolder
        showRatesImportProgress
        FDIstatus
        keyCountry1
        keyCountry2
        Countries
        rateCurves
        rateScenarios
        mapCountryToCurveName
        annuityValue
        inputFolderLifeTables
        TimeZone
        ReferenceTime
        
        MarketReferenceParameters
        MainScenarioParameters
        ESGModelTypes

        SampleAnnuityValues struct % Store annuity values for all types
        axesHandles         % Array to store axes handles
        
        
    end
    properties (Access = public)
        projectBasePath
        deployed

    end
    
    methods (Access = private) 
        function app = createSubplots(app,selectedTab,plotPanel,types)
            %annuityTypes = enumeration('AnnuityType');
            numTypes = length(types);
            nameTypes = metaclass(types).Name;
            tabName = selectedTab.Title;
           
            switch numTypes
                case 1
                    nRows = 1;
                    nCols = 1;
                case 2
                    nRows = 2;
                    nCols = 1;


                case 3
                    nRows = 3;
                    nCols = 1;

                case 4
                    nRows = 2;
                    nCols = 2;
                otherwise
                    nRows = ceil(sqrt(numTypes));
                    nCols = ceil(numTypes / nRows);
            end
            
            
            % Calculate grid dimensions
            
            t = tiledlayout(plotPanel, nRows, nCols); 

            % Create subplots and store axes handles
            % Initialize app.axesHandles as an empty cell array
            app.axesHandles = {};
           
            for i = 1:numTypes
                ax1 = nexttile(t);
                %ax = uiaxes(plotPanel);
                %app.axesHandles(i) = uiaxes(plotPanel);  
                title(ax1, char(types(i)));
                app.axesHandles{i} = ax1; % Append the handle to the array
                %app.setofAxes(i) = ax;
            end
        end

        function allAnnuityValues = calculateSampleAnnuityValues(app,parametersToVary)
            
            % set up user characteristics

            gender = app.GlobalAppArgs.person.gender;
            startAge = app.GlobalAppArgs.person.startAge;
            personCountry = app.GlobalAppArgs.person.country;
            initialValue = app.GlobalAppArgs.person.initialValue;
            ownerPayment = app.GlobalAppArgs.person.ownerPayment;
            ownerPaymentFrequency = app.GlobalAppArgs.person.ownerPaymentFrequency;
            
            ownerPaymentIncreaseRate =  app.GlobalAppArgs.person.inflationRateAssumption;
            ownerIncomeDeferment = app.GlobalAppArgs.person.deferment;
            maxNumPayments = app.GlobalAppArgs.person.maxNumPayments;
            ownerPaymentStartDate = app.GlobalAppArgs.Dates.startDateScenario;


            %Initialise a person object to reflect the users inputs
            tableFilePath = app.inputFolderLifeTables;
            % storedMortalityFile = app.GlobalAppArgs.Folders.storedMortalityFile;
            % baseLifeTable  = utilities.LifeTableUtilities.loadOrCreateBaseTable(storedMortalityFile);
            
            
            %ownerCashflowStrategy = CashflowStrategy('AnnualAmount',ownerPayment,'StartDate',ownerPaymentStartDate ,...
             %   'Frequency',ownerPaymentFrequency,'InflationRate',ownerPaymentIncreaseRate,...
                %'BaseLifeTable',baseLifeTable,'MaxNumPayments',maxNumPayments );
            ownerCashflowStrategy = CashflowStrategy('AnnualAmount',ownerPayment,'StartDate',ownerPaymentStartDate ,...
                'Frequency',ownerPaymentFrequency,'InflationRate',ownerPaymentIncreaseRate,...
                'MaxNumPayments',maxNumPayments );
            
            app.Person = Person('Gender',gender,'Age',startAge,'Country',personCountry,'InitialValue',initialValue,...
                'TargetIncome',ownerPayment,'IncomeDeferement',ownerIncomeDeferment,'CashflowStrategy',ownerCashflowStrategy);
            person = app.Person;         
            allAnnuityValues = calculateSampleAnnuityValues(person,parametersToVary);
        end

        function plotAnnuityData(app, allAnnuityValuesToPlot,annuityParametersToPlot)
            [annuityTypes,annuityTypeNames] = enumeration('AnnuityType');
            
            % levelRates =-0.02:0.01:0.10;
            colors = ['r', 'g', 'b', 'k'];

            for i = 1 : length(annuityTypes)
              annuityTypeToAccess = annuityTypes(i);
              annuityTypeName =annuityTypeNames(i);
              
              % Find the index of the AnnuityType
              index = find(strcmp({allAnnuityValuesToPlot.AnnuityType}, annuityTypeToAccess));

              % Access the Data
              if ~isempty(index)
                  dataForAnnuityType = allAnnuityValuesToPlot(index).Data;
              else
                  disp(['AnnuityType "', annuityTypeToAccess, '" not found.']);
              end

              %annuityValues = allAnnuityValuesToPlot.(char(annuityType));
              ax = app.axesHandles{i}; % Access the correct axes handle
              hold(ax, 'on'); % Hold the current axes
              
              
              [annuityInputType,annuityInputTypeNames] = enumeration('AnnuityInputType');
        
              linePlotParameterName= annuityInputTypeNames{annuityParametersToPlot(2)};
              xAxisParameterName = annuityInputTypeNames{annuityParametersToPlot(1)};
                % yAxisParameterName = annuityInputTypeNames{annuityParametersToPlot(2)};
              yAxisParameterName = 'AnnuityValue';

              % select unique values for multiple lines plots
              linePlotParameters = unique([dataForAnnuityType.(linePlotParameterName)]);


              for j = 1:length(linePlotParameters)
                  
                  % Find indices where linePlotParameter matches the current unique value
                  indices = [dataForAnnuityType.(linePlotParameterName)] == linePlotParameters(j);

                  % Extract the x and y values for the selected indices
                  xAxisValues = [dataForAnnuityType(indices).(xAxisParameterName)];
                  yAxisValues = [dataForAnnuityType(indices).(yAxisParameterName)];
                  
                  
                  % % Extract the annuity terms and values for the current interest rate
                  % xAxisValues = dataForAnnuityType.(xAxisParameterName);
                  % yAxisValues = dataForAnnuityType.(yAxisParameterName);

                  % Plot the data 
                  %TODO make denomination sensitive tot he variable being
                  %plotted
                  plot(ax,xAxisValues, yAxisValues, colors(mod(j-1,length(colors))+1), 'DisplayName', sprintf('%.2f%%', linePlotParameters(j)*100));
              end
              hold(ax, 'off');
            
              % Add labels and legend
              xlabel(ax,xAxisParameterName);
              ylabel(ax, yAxisParameterName);
              title(ax, sprintf([yAxisParameterName ' vs '  xAxisParameterName]));
              subtitle(ax,annuityTypeName)
              lgd = legend(ax, 'show'); % Display the legend
              title(lgd,linePlotParameterName);

              grid (ax,'on'); % Add gridlines (optional)

            end

        end

        function marketData = loadMarketDataToApp(app,nodeName)
            if ~isempty(nodename)
                if  contains(nodeName,app.InterestratesNode.Text)
                    marketData = marketdata.MarketDataReader.generateAndLoadRatesData(inputFolderRates, rateFileConstruct, cacheFolder,rateCurveEndDates,outputFolder, displayRateCurveNames, verbose);
                else
                    disp("load default rates")
                    marketData = marketdata.MarketData();
                end
                if contains(nodeName,app.EquityIndexesNode.Text)
                    timeZoneMapping = containers.Map;
                    % Define the directory where your index data files are located
                    % in future upgrade to read file paths
                    %inputFilePath = 'G:\My Drive\Kaparra Software\UNSWLecture\AccumReturnData';

                    indexMarketData = marketdata.MarketDataReader.readIndexPriceFiles(marketPriceInputFilePath,timeZoneMapping,outputFolder,verbose);
                    indexMarketData.Time=datetime(indexMarketData.Time +referenceTime,'Format','dd/MM/uuuu HH:mm:ss'); % assumes prices are returned as start of day. if the routine is changed to provide prices at close of business remove referenceTime

                    marketData.MarketIndexPrices = indexMarketData;

                end

                if contains(nodeName,app.SimulatedequityindexesNode.Text)

                    marketData = marketdata.MarketDataReader.generateAndLoadRatesData(inputFolderRates, rateFileConstruct, cacheFolder,rateCurveEndDates,outputFolder, displayRateCurveNames, verbose);
                end
            else
                marketData = marketdata.MarketDataReader.generateAndLoadRatesData(inputFolderRates, rateFileConstruct, cacheFolder,rateCurveEndDates,outputFolder, displayRateCurveNames, verbose);
            end
        end

        function plotMarketData(app,marketDataPeriodToPlot,simulationStartDate)


            colors = ['r', 'g', 'b', 'k'];
            ax = app.axesHandles{1}; % Access the correct axes handle

            marketData = app.MarketData;

            
            [marketUniverseReferencePortfolioReturns, marketUniverseIndexValues, marketUniverseForwardRatesTT] = generateMarketDataTimeSeries(app,simulationStartDate);
            indexVarNames = marketUniverseIndexValues.Properties.VariableNames(1:end);

            % Filter data based on marketDataPeriodToPlot
            startDate = marketDataPeriodToPlot(1);
            endDate = marketDataPeriodToPlot(2);

            % Assuming 'Time' is a datetime variable in your tables
            filteredIndexValues = marketUniverseIndexValues(marketUniverseIndexValues.Time >= startDate & ...
                marketUniverseIndexValues.Time <= endDate, :);
            rebasedIndexValues = utilities.TimeTableUtilities.rebaseTimeSeries(filteredIndexValues, 'Time', 10000, startDate); 
            filteredForwardRates = marketUniverseForwardRatesTT(marketUniverseForwardRatesTT.Time >= startDate & ...
                marketUniverseForwardRatesTT.Time <= endDate,:);
            filteredRefReturns = marketUniverseReferencePortfolioReturns(marketUniverseReferencePortfolioReturns.Time >= startDate & ...
                marketUniverseReferencePortfolioReturns.Time <= endDate, :);

            hold(ax, 'on');
            grid(ax, 'on');
            plot(ax, rebasedIndexValues.Time, rebasedIndexValues{:, indexVarNames});
            hold(ax, 'off');

            % Add labels and legend
            xlabel(ax, 'Time');
            ylabel(ax, 'Market Index Value');
            title(ax, sprintf('Market index value between %s and %s', datestr(startDate), datestr(endDate)));
            legend(ax, indexVarNames);

            % --- Plot on the second axes ---
            ax = app.axesHandles{2};
            returnVarNames = [{"Forward Rates"}, {"Market Reference Returns"}];
            hold(ax, 'on');
            grid(ax, 'on');

            % Adjust the indexing for filteredForwardRates based on your actual data structure
            plot(ax, filteredForwardRates.Time, filteredForwardRates.Rate);
            plot(ax, filteredRefReturns.Time, filteredRefReturns.Benchmark_Return_Annualized);
            legend(ax, returnVarNames);
            hold(ax, 'off');

            % Add labels and legend
            xlabel(ax, 'Time');
            ylabel(ax, 'Forward Rates');
            title(ax, sprintf('Forward rates between %s and %s', datestr(startDate), datestr(endDate)));
             % Add vertical line and labels for simulation start date on both axes
            
            for i = 1:numel(app.axesHandles)
                 ax = app.axesHandles{i};
                 hold(ax, 'on');
                 hLine = xline(ax, simulationStartDate, 'k--', 'LineWidth', 1.5);

                 % Add arrows and labels
                 yLimits = ylim(ax); % Get the y-axis limits
                 yPosition = yLimits(1) + 0.1 * (yLimits(2) - yLimits(1)); % Position labels slightly above the bottom

                 text(ax, simulationStartDate - days(30), yPosition, '\leftarrow Historical', 'HorizontalAlignment', 'right');
                 text(ax, simulationStartDate + days(30), yPosition, 'Simulated \rightarrow', 'HorizontalAlignment', 'left');
                 
                 % Exclude the vertical line from the legend
                 hLine.Annotation.LegendInformation.IconDisplayStyle = 'off';
                 hold(ax, 'off');
             end

        end
        function plotPortfolioSimulationData(app,marketDataPeriodToPlot,simulationStartDate)


            colors = ['r', 'g', 'b', 'k'];
            ax = app.axesHandles{1}; % Access the correct axes handle
            
            % Create local start and end dates 
            startDate = marketDataPeriodToPlot(1);
            endDate = marketDataPeriodToPlot(2);
            simulationResults = app.InvestingForIncomeSimulationResult;


            outputVariableName = simulationResults.Timetable.Properties.VariableNames{6};
            

            portfolioSimulationData = simulationResults.Timetable;

%TODO add number of instruments
numberOfPortfolioInstruments = length(simulationResults.AssetPortfolio.PortfolioHoldings);


            tradedPortfolioComponentsNames = portfolioSimulationData.Properties.VariableNames(7:(6+numberOfPortfolioInstruments));
            tradedPortfolioComponents = portfolioSimulationData(:, tradedPortfolioComponentsNames);
            %[tradedPortfolioComponents,surplusOutput.TradedPortfolioValue] = portfolioSimulationData.Properties.VariableNames(7:9);
            % Select the relevant data
            

            % Sum the components row by row
            tradedPortfolioValue = sum(table2array(tradedPortfolioComponents), 2);
            liabilityValues = portfolioSimulationData.("PV of Portfolio Owner Payments Current Rate Curve");
            surplusValues = tradedPortfolioValue- liabilityValues;

            % Create a new timetable with the summed values
            surplusOutput = timetable(portfolioSimulationData.currentDate,...
                tradedPortfolioValue, ...
                liabilityValues,...
                surplusValues,...
                'VariableNames', {'TradedPortfolioValue','Liability Values','Surplus Values'}); 

           
            surplusOutputValues = [-surplusOutput.("Liability Values"),...
                surplusOutput.("Surplus Values"),...
                surplusOutput.TradedPortfolioValue];
            
            surplusVariablesNames =  [{surplusOutput.Properties.VariableNames{2}},...
                {surplusOutput.Properties.VariableNames{3}},...
                {surplusOutput.Properties.VariableNames{1}}];

             % --- Plot on the first axes ---          

            hold(ax, 'on');
            grid(ax, 'on');
            plot(ax, surplusOutput.Time, surplusOutputValues)
            hold(ax, 'off');

            % Add labels and legend
            xlabel(ax, 'Time');
            ylabel(ax, 'Surplus');
            title(ax, sprintf('Surplus between %s and %s', datestr(startDate), datestr(endDate)));
            legend(ax, surplusVariablesNames);

            % --- Plot on the second axes ---
            ax = app.axesHandles{2};
                       
            hold(ax, 'on');
            grid(ax, 'on');
            
            % Dynamically plot the components using a loop
            for i = 1:numel(tradedPortfolioComponentsNames)
                plot(ax, tradedPortfolioComponents.currentDate, tradedPortfolioComponents.(tradedPortfolioComponentsNames{i}));
            end

            % Plot the summed values
            plot(ax, surplusOutput.Time, surplusOutput.TradedPortfolioValue);           
            legend(ax, [tradedPortfolioComponentsNames,'TradedPortfolioValue']);
            
            
            hold(ax, 'off');

            % Add labels and legend
            xlabel(ax, 'Time');
            ylabel(ax, 'Asset values');
            title(ax, sprintf('Asset values between %s and %s', datestr(startDate), datestr(endDate)));

            % --- Plot on the third axes ---
            ax = app.axesHandles{3};
                       
            hold(ax, 'on');
            grid(ax, 'on');
            

            liabilityValues = [portfolioSimulationData.("PV of Portfolio Owner Payments Current Rate Curve"),...
                portfolioSimulationData.("PV of Portfolio Owner Payments StartDate Rate Curve")];
           
            plot(ax,portfolioSimulationData.currentDate,liabilityValues)


            legend(ax, ["PV of Portfolio Owner Payments Current Rate Curve","PV of Portfolio Owner Payments StartDate Rate Curve"]);
            
            
            hold(ax, 'off');

            % Add labels and legend
            xlabel(ax, 'Time');
            ylabel(ax, 'Liability values');
            title(ax, sprintf('Liabilitity values between %s and %s', datestr(startDate), datestr(endDate)));
            % Add vertical line and labels for simulation start date on both axes
            % 
            for i = 1:numel(app.axesHandles)
                ax = app.axesHandles{i};
                hold(ax, 'on');
                hLine = xline(ax, simulationStartDate, 'k--', 'LineWidth', 1.5);

                % Add arrows and labels
                yLimits = ylim(ax); % Get the y-axis limits
                yPosition = yLimits(1) + 0.1 * (yLimits(2) - yLimits(1)); % Position labels slightly above the bottom

                text(ax, simulationStartDate - days(30), yPosition, '\leftarrow Historical', 'HorizontalAlignment', 'right');
                text(ax, simulationStartDate + days(30), yPosition, 'Simulated \rightarrow', 'HorizontalAlignment', 'left');

                % Exclude the vertical line from the legend
                hLine.Annotation.LegendInformation.IconDisplayStyle = 'off';
                hold(ax, 'off');
            end

        end

        function [marketUniverseWeightedReturns,marketUniverseIndexValues,marketUniverseForwardRatesTT] = generateMarketDataTimeSeries(app,simulationStartDate)
            marketData = app.MarketData;
            app.MarketReferenceParameters = scenarios.buildMarketReferencePortfolioScenarioParameters(app.GlobalAppArgs);
            marketReferencePortfolioScenarioBuilder = scenarios.GenericPortfolioScenarioBuilder( app.MarketReferenceParameters);
            marketReferencePortfolioScenario = scenarios.InitialiseScenarioWorkflows.initializeScenario(marketReferencePortfolioScenarioBuilder,  app.MarketReferenceParameters); %generate a scenario with no market data yet
            marketReferencePortfolioScenario = scenarios.InitialiseScenarioWorkflows.simulateScenarioMarketData(marketReferencePortfolioScenario,marketData,'simulationStartDate', simulationStartDate,'ESGModelType',app.GlobalAppArgs.marketUniverse.ESGModelType);
            %marketReferencePortfolioScenario = scenarios.InitialiseScenarioWorkflows.simulateScenarioMarketData(marketReferencePortfolioScenario,marketData);
            marketData.AssetReturns = marketReferencePortfolioScenario.ScenarioMarketData.AssetReturns;

            marketUniverseWeightedReturns = utilities.InvestmentReturnUtilities.annualizeReturns(marketReferencePortfolioScenario.ScenarioMarketData.AssetReturns);
            marketUniverseIndexValues = marketReferencePortfolioScenario.ScenarioMarketData.MarketIndexPrices;
            marketUniverseForwardRatesTT = marketReferencePortfolioScenario.ScenarioMarketSimulationParameters.ForwardRatesTT;
            app.MarketData = marketData;
        end

        function mainSimulationScenario = generateMainScenarioMarketDataTimeSeries(app,simulationStartDate)

            marketData = app.MarketData;
            mainScenarioParameters = scenarios.buildMainScenarioParameters(app.GlobalAppArgs);
            app.MainScenarioParameters = mainScenarioParameters;
            mainSimulationScenarioBuilder = scenarios.MainSimulationScenarioBuilder(app.MainScenarioParameters);
            mainSimulationScenario = scenarios.InitialiseScenarioWorkflows.initializeScenario(mainSimulationScenarioBuilder,  mainScenarioParameters);
            mainSimulationScenario = scenarios.InitialiseScenarioWorkflows.simulateScenarioMarketData(mainSimulationScenario,marketData,'simulationStartDate', simulationStartDate,'ESGModelType',app.GlobalAppArgs.marketUniverse.ESGModelType);
            marketData.AssetReturns = mainSimulationScenario.ScenarioMarketData.AssetReturns;


        end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % rootPath = 'G:\My Drive\Kaparra Software\Rates Analysis\Resources';
            % addpath(fullfile(rootPath)) % assumes pwd is 'G:\My Drive\Kaparra Software\Rates Analysis' 
            % newPath = utilities.PathManagement.addPaths(rootPath,'archive');
            

            inputArgs = runtimeclasses.initialiseInputArguments();
           
            app.InputArgs = inputArgs;
            app.GlobalAppArgs  = inputArgs;

            app.TimeZone = inputArgs.Dates.localTimeZone;
            app.ReferenceTime = inputArgs.Dates.referenceTime;
            format(inputArgs.runtime.numericalOutputformat);
            floatingPointTolerance = inputArgs.runtime.floatingPointTolerance;
            
            app.projectBasePath = fileparts(mfilename('fullpath'));
            disp(app.projectBasePath)
            run('ratesAnalysisToolConfig.m'); % Load configurations

            app.startDateRates = inputArgs.Dates.startDateRates;
            app.inputFolderRates = inputArgs.Folders.inputFolderRates;
            app.inputFileNameRates = inputArgs.Folders.inputFileNameRates;
            app.inputFolderLifeTables = inputArgs.Folders.inputFolderMortality;
            %storedMortalityFile = inputArgs.Folders.storedMortalityFile;

            
            %baseLifeTable  = utilities.LifeTableUtilities.loadOrCreateBaseTable( app.inputFolderLifeTables);
            %baseLifeTable  = utilities.LifeTableUtilities.loadOrCreateBaseTable(storedMortalityFile);
            
            app.MarketScenarioStartDate = inputArgs.Dates.startDateScenario;
            app.MarketScenarioEndDate = inputArgs.Dates.endDateScenario;
            
            %set app input variables for the user

            gender = app.GlobalAppArgs.person.gender;
            startAge = app.GlobalAppArgs.person.startAge;
            personCountry = app.GlobalAppArgs.person.country;
            initialValue = app.GlobalAppArgs.person.initialValue;
            ownerPayment = app.GlobalAppArgs.person.ownerPayment;
            ownerPaymentFrequency = app.GlobalAppArgs.person.ownerPaymentFrequency;
            ownerPaymentStartDate = app.GlobalAppArgs.person.paymentStartDate;
            ownerIncomeDeferement = app.GlobalAppArgs.person.deferment;
            ownerIncomeIncreaseRate = app.GlobalAppArgs.person.inflationRateAssumption;
            maxNumOwnerPayments = app.GlobalAppArgs.person.maxNumPayments;


            app.AgeInputField.Value = startAge;
            app.CountryInputField.Value = personCountry;
            switch gender
                case utilities.GenderType.Female
                    app.MygenderisButtonGroup.SelectedObject = app.FemaleButton;

                case utilities.GenderType.Male
                    app.MygenderisButtonGroup.SelectedObject = app.MaleButton;

                otherwise
                    app.MygenderisButtonGroup.SelectedObject = app.OtherGenderButton;
            end
            app.keyCountry2 = keyCountry2; %set initial country 2

            app.targetIncomeSlider.Value = ownerPayment/1000;
            app.incomeFrequencyKnob.Value = utilities.FrequencyType.getAlias(ownerPaymentFrequency);
            app.incomeincreaseSlider.Value = ownerIncomeIncreaseRate*100;
           
            app.investibleAssetsSlider.Value = initialValue/1000000;

            app.AnnuityIncomeEditField.Value = ownerPayment;
            app.GuaranteedincreaserateEditField_2.Value =app.incomeincreaseSlider.Value;
            app.AnnuityTermEditField.Value = maxNumOwnerPayments;
            app.IncomedeferredyrsEditField_2.Value = ownerIncomeDeferement;


            %Initialise a person object to reflect the users inputs
            %tableFilePath = app.inputFolderLifeTables;
            % ownerCashflowStrategy = CashflowStrategy('AnnualAmount',ownerPayment,'StartDate',...
            %     ownerPaymentStartDate ,'Frequency',ownerPaymentFrequency,...
            %     'InflationRate',app.incomeincreaseSlider.Value,...
            %     'BaseLifeTable',baseLifeTable);

           
            % initialise the mortality table list

            [m,s] = enumeration('TableNames');
            app.MortalityTableListBox.Items = s;
            app.MortalityTableListBox.Value = app.GlobalAppArgs.Mortality.mortalityTableName;
            selectedIndice = find(ismember(m,app.MortalityTableListBox.Value));
            mortalityTableName = m(selectedIndice);
            

            % [m,s] = enumeration('MortalityDataSourceNames');
            % app.MortalityDataSourceListBox.Items = s;
            % app.MortalityDataSourceListBox.Value = app.GlobalAppArgs.Mortality.mortalityDataSource;
            % mortalityDataSource = app.MortalityDataSourcelistBox.Value;

            mortalityDataSource =app.GlobalAppArgs.Mortality.mortalityDataSource;

            [m,s] = enumeration('MortalityImprovementStrategyNames');
            app.MortalityimprovementListBox.Items = s;
            app.MortalityimprovementListBox.Value = app.GlobalAppArgs.Mortality.mortalityImprovementStrategy;

            selectedIndice = find(ismember(m,app.MortalityimprovementListBox.Value));
            mortalityImprovementStrategy = m(selectedIndice);
            %mortalityImprovementStrategy = num2cell(selectedStrategy);  


             ownerCashflowStrategy = CashflowStrategy('AnnualAmount',ownerPayment,'StartDate',...
                ownerPaymentStartDate ,'Frequency',ownerPaymentFrequency,...
                'InflationRate',app.incomeincreaseSlider.Value,'TableName',mortalityTableName,'MortalityDataSource', mortalityDataSource);
            
            app.Person = Person('Gender',gender,'Age',startAge,'Country',personCountry,'InitialValue',initialValue,...
                'TargetIncome',ownerPayment,'IncomeDeferement',ownerIncomeDeferement,'CashflowStrategy',ownerCashflowStrategy);
           
            
            % initialise market Universe parameters
            app.SampleDatesKnob.ItemsData = xlsFileNames(1:4);
            app.SampleDatesKnob.Value = inputFileNameRates;
            app.MarketUniverseTickers = inputArgs.marketUniverse.benchmarkPortfolioTickers;                      
            
            app.rateScenarios = app.GlobalAppArgs.RateFile.rateScenarios;


            %initialise main portfolio investment strategy definitions
            
            app.PortfoliostartdateDatePicker.Value =  app.MarketScenarioStartDate;
            app.TargetIncome.Value = app.targetIncomeSlider.Value;
            app.InitialPortfolioValue.Value =  app.investibleAssetsSlider.Value;
            app.PlanningHorizon.Value = years(app.MarketScenarioEndDate  - app.MarketScenarioStartDate);

            %initialise hedge annuity selection parameter controls
            [m,s] = enumeration('AnnuityType');
            app.AnnuityTypeListBox.Items = s ;
            app.AnnuityTypeListBox.Value = app.GlobalAppArgs.hedgeAnnuity.annuityType;

            app.IncomeToAnnuitiseEditField.Value = app.GlobalAppArgs.hedgeAnnuity.guaranteedPayment;

            app.GuaranteedincreaserateEditField.Value = app.GlobalAppArgs.hedgeAnnuity.GuaranteedPaymentIncreaseRate;
            app.IncomedeferredyrsEditField.Value = app.GlobalAppArgs.hedgeAnnuity.GuaranteedIncomeDeferement;
            app.HedgeAnnuityStartDateDatePicker.Value = app.GlobalAppArgs.hedgeAnnuity.annuityStartDate;
            app.MaxnumberofpaymentsEditField.Value  = app.GlobalAppArgs.hedgeAnnuity.maxNumPayments;
            app.SamplevaluationageEditField.Value = app.GlobalAppArgs.person.startAge;

            % initialise main portfolio trading strategy
            [m,s] = enumeration('TradingStrategyType');

            app.StrategyListBox.Items =  s;
            % Get the selected strategy names from the global args
            selectedStrategies = app.GlobalAppArgs.portfolioParameters.tradingStrategyTypes;

            % Convert the enumeration members to strings
            for i = 1 : length(selectedStrategies)
                selectedStrategyNames{i} = utilities.EnumUtilities.getString(selectedStrategies{i});
            end

            % Assign the string values to the ListBox
            app.StrategyListBox.Value = selectedStrategyNames;
            
        
            %Initiliase sampleannuitytype to display
            [m,s] = enumeration('AnnuityType');
            
            app.AnnuityTypeToDisplay.Items = s ;
            app.AnnuityTypeToDisplay.ValueIndex = 1;
            
            %Initiliase annuityInputVariables to vary
            [m,s] = enumeration('AnnuityInputType');
            app.XAxisToPlotListBox.Items = s;
            app.XAxisToPlotListBox.ValueIndex = app.GlobalAppArgs.analysisDefaults.annuityParametersToPlot(1);

            app.LineToPlotListBox.Items = s;
            app.LineToPlotListBox.ValueIndex = app.GlobalAppArgs.analysisDefaults.annuityParametersToPlot(2);

            app.InputVariablesForTableListBox.Items = s;
            app.InputVariablesForTableListBox.ValueIndex = app.GlobalAppArgs.analysisDefaults.annuityParametersForTable;

        end

        % Value changed function: targetIncomeSlider
        function targetIncomeSliderValueChanged(app, event)
            value = app.targetIncomeSlider.Value*1000;
            app.GlobalAppArgs.person.ownerPayment = value;
        end

        % Value changed function: investibleAssetsSlider
        function investibleAssetsSliderValueChanged(app, event)
            value = app.investibleAssetsSlider.Value*1000000;
            app.GlobalAppArgs.person.initialValue = value;
        end

        % Value changed function: incomeFrequencyKnob
        function incomeFrequencyKnobValueChanged(app, event)
            value = app.incomeFrequencyKnob.Value;
            value = utilities.FrequencyType.fromAlias(value);
             app.GlobalAppArgs.person.contributionFrequency = value;
        end

        % Value changed function: incomeincreaseSlider
        function incomeincreaseSliderValueChanged(app, event)
            value = app.incomeincreaseSlider.Value/100;
             app.GlobalAppArgs.person.inflationRateAssumption = value;
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            for i = 1:length(checkedNodes)
                nodeName(i) = selectedNodes(i).Text;
            end
            % ... process the nodeName (e.g., display it, store it in an array, etc.) ...
            marketData = app.loadMarketDataToApp(nodeName);
            app.MarketData = marketData;

        end

        % Button pushed function: SelectaratefileButton
        function SelectaratefileButtonPushed(app, event)
           [filename, pathname] = uigetfile({'*.txt', 'Text Files (*.txt)'; ...
                                   '*.*',  'All Files (*.*)'}); 
            if isequal(filename, 0)  % User cancelled
                return; 
            end
            fullFilePath = fullfile(pathname, filename);
            app.inputFolderRates = pathname;
            app.inputFileNameRates = filename;
            app.startDateRates =  marketdata.extractDateEIOPAFile(filename);
            app.SelectadateDatePicker.Value = app.startDateRates; 
        end

        % Button pushed function: PlotratesforselectedfileButton
        function PlotratesforselectedfileButtonPushed(app, event)
             % Clear axes 1
            cla(app.UIAxes3);

            % Clear axes 2
            cla(app.UIAxes4); 

            app.keyCountry2 = app.Country2DropDown.Value;
            app.keyCountry1 = app.Country1DropDown.Value;
            
            utilities.plotInterestRates(app.UIAxes3,app.rateScenarios,app.rateCurves,app.keyCountry1,app.startDateRates,app.UIAxes4,app.keyCountry2)
            app.Countries = [app.keyCountry1,app.keyCountry2];
        end

        % Button pushed function: LoadselectedratefileButton
        function LoadselectedratefileButtonPushed(app, event)
            display(app.UIFigure);
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = marketdata.readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
            display(app.rateCurves) 
            app.SelectadateDatePicker.Value = app.startDateRates;
        end

        % Value changed function: SelectadateDatePicker
        function SelectadateDatePickerValueChanged(app, event)
            value = app.SelectadateDatePicker.Value;
            localStartDateRates = app.SelectadateDatePicker.Value;
            app.inputFileNameRates = 'EIOPA_RFR_'+string(year(localStartDateRates))+num2str(month(localStartDateRates), '%02d')+ string(day(localStartDateRates)) +'_Term_Structures.xlsx';
            app.startDateRates =localStartDateRates;
            
        end

        % Button down function: SampleInterestratesTab
        function SampleInterestratesTabButtonDown(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.keyCountry1 = app.CountryInputField.Value; % set initial country 1
            
                        
            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = marketdata.readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,app.startDateRates, app.outputFolder,app.showRatesImportProgress);
            display(app.mapCountryToCurveName)
            
            app.Country2DropDown.Items = app.mapCountryToCurveName.keys;
            app.Country1DropDown.Items = app.mapCountryToCurveName.keys;
            display(app.Country1DropDown.Items);
            
            app.Country2DropDown.ItemsData = app.mapCountryToCurveName.keys;
            app.Country1DropDown.ItemsData = app.mapCountryToCurveName.keys;
            
            app.Country2DropDown.Value = app.keyCountry2;
            app.Country1DropDown.Value = app.keyCountry1;
            app.Countries = [app.Country1DropDown.Value,app.Country2DropDown.Value];

        end

        % Value changed function: SampleDatesKnob
        function SampleDatesKnobValueChanged(app, event)
            value = app.SampleDatesKnob.Value;
            localStartDateRates = marketdata.extractDateEIOPAFile(value);
            app.startDateRates = localStartDateRates;
            app.SelectadateDatePicker.Value = app.startDateRates;
            app.inputFileNameRates = 'EIOPA_RFR_'+string(year(localStartDateRates))+num2str(month(localStartDateRates), '%02d')+ string(day(localStartDateRates)) +'_Term_Structures.xlsx';
            
        end

        % Button pushed function: OverlayanotherdateButton
        function OverlayanotherdateButtonPushed(app, event)
            app.keyCountry2 = app.Country2DropDown.Value;
            app.keyCountry1 = app.Country1DropDown.Value;
            
            value = app.SampleDatesKnob.Value;
            localStartDateRates = marketdata.extractDateEIOPAFile(value);

            [app.rateCurves,app.rateScenarios,app.mapCountryToCurveName] = marketdata.readRateFilesAndCreateRateCurves(app.inputFolderRates,app.inputFileNameRates,localStartDateRates, app.outputFolder,app.showRatesImportProgress);
            
            utilities.plotInterestRates(app.UIAxes3,app.rateScenarios,app.rateCurves,app.keyCountry1,localStartDateRates,app.UIAxes4,app.keyCountry2)

            app.Countries = [app.keyCountry1,app.keyCountry2];
        end

        % Button down function: FinancialmarketsTab
        function FinancialmarketsTabButtonDown(app, event)
           selectedTab = app.TabGroup.SelectedTab;
           tag = 'PlotMarketData';
           plotPanel = findobj(selectedTab.Children, 'Tag', tag); % Find the panel by its Tag                 
            subplottypes = ["Market Indexes","Rates of return"];
           app.createSubplots(selectedTab,plotPanel,subplottypes);
           
           simulationStartDate = app.GlobalAppArgs.Dates.marketSimulationStartDate;
           app.SimulationStartDateDatePicker.Value = simulationStartDate;
          
           
          app.MarketScenarioToDatePicker.Value = app.MarketScenarioEndDate;
          app.MarketScenarioFromDatePicker.Value = app.MarketScenarioStartDate;
           
           app.ESGModelTypes = utilities.EconomicScenarioGeneratorType.getEconomicScenarioGeneratorTypes();
            % 2. Convert the list to a cell array of strings
           %generatorTypeName = utilities.EnumUtilities.getString(app.ESGModelTypes(1));
           generatorTypeNames = utilities.EnumUtilities.getStringArray(app.ESGModelTypes);


           app.MarketReferenceParameters = scenarios.buildMarketReferencePortfolioScenarioParameters(app.GlobalAppArgs);
           availableIndices = app.MarketReferenceParameters.portfolio.allowablePortfolioHoldings';
           weights = app.MarketReferenceParameters.portfolio.benchmarkPortfolioWeights';
           riskPremiums = app.MarketReferenceParameters.portfolio.riskPremiums';
           simParametersTableData = table(availableIndices,weights,riskPremiums);
           app.UITableMarketReferenceSimData.Data = simParametersTableData;

           app.ESGModelListBox.Items = generatorTypeNames;
           app.ESGModelListBox.Value = generatorTypeNames{1};
           app.ESGModelListBox.ItemsData = app.ESGModelTypes;


        end

        % Button pushed function: LoadMarketDataButton
        function LoadMarketDataButtonPushed(app, event)
            app.MarketData = marketdata.initialiseMarketDataObject;
            
        end

        % Button down function: MortalityandAnnuitiesTab
        function MortalityandAnnuitiesTabButtonDown(app, event)
            % Create subplots for each annuity type (within the tab's UIAxes)
            selectedTab = app.TabGroup.SelectedTab;
            tag = 'PlotofannuityvaluesversusmaximumyearsPanel';
            plotPanel = findobj(selectedTab.Children, 'Tag', tag); % Find the panel by its Tag
            annuityTypes = enumeration('AnnuityType');
            app.createSubplots(selectedTab,plotPanel,annuityTypes);           

        end

        % Button pushed function: PopulateAnnuityValuesTableButton
        function PopulateAnnuityValuesTableButtonPushed(app, event)
            % Initialize data (replace with your actual data loading)
            parametersToVary = app.InputVariablesForTableListBox.ValueIndex; % [1,4];
            allAnnuityValuesToPlot = app.calculateSampleAnnuityValues(parametersToVary);
            selectedAnnuityType = app.AnnuityTypeToDisplay.Value; % Get selected type
            selectedDataStruct = allAnnuityValuesToPlot(strcmp({allAnnuityValuesToPlot.AnnuityType}, selectedAnnuityType)).Data;
            %app.AnnuityValueTable.Data = selectedData;
            selectedData = struct2table(selectedDataStruct);
            app.UISampleAnnuityValuesTable.Data = selectedData;
            app.UISampleAnnuityValuesTable.ColumnName = selectedData.Properties.VariableNames;
        end

        % Button pushed function: PlotannuityvaluesButton
        function PlotannuityvaluesButtonPushed(app, event)
             % Plot data on each subplot
             parametersToVary = [app.LineToPlotListBox.ValueIndex app.XAxisToPlotListBox.ValueIndex ];
             %parametersToVary = app.GlobalAppArgs.analysisDefaults.annuityParametersToPlot;%app.GlobalAppArgs.analysisDefaults.annuityParametersToPlot;
             allAnnuityValuesToPlot = app.calculateSampleAnnuityValues(parametersToVary);
            app.plotAnnuityData(allAnnuityValuesToPlot,parametersToVary);
        end

        % Value changed function: MarketScenarioFromDatePicker
        function MarketScenarioFromDatePickerValueChanged(app, event)
            marketScenarioStartDate = app.MarketScenarioFromDatePicker.Value;
            marketScenarioStartDate = marketScenarioStartDate +app.ReferenceTime;
            marketScenarioStartDate.TimeZone = app.TimeZone;            
            app.MarketScenarioStartDate = marketScenarioStartDate;
            app.GlobalAppArgs.Dates.startDateScenario = marketScenarioStartDate;
            app.GlobalAppArgs.Dates.startDates = marketScenarioStartDate;
        end

        % Value changed function: MarketScenarioToDatePicker
        function MarketScenarioToDatePickerValueChanged(app, event)
            marketScenarioEndDate = app.MarketScenarioToDatePicker.Value;
            marketScenarioEndDate = marketScenarioEndDate +app.ReferenceTime;
            marketScenarioEndDate.TimeZone = app.TimeZone;
            app.MarketScenarioEndDate = marketScenarioEndDate;
            app.GlobalAppArgs.Dates.endDateScenario = marketScenarioEndDate;
            app.GlobalAppArgs.Dates.endDates = marketScenarioEndDate;


        end

        % Button pushed function: PlotMarketDataButton
        function PlotMarketDataButtonPushed(app, event)
            marketDataPeriodToPlot = [app.MarketScenarioStartDate,app.MarketScenarioEndDate];
            %marketIndexesToPlot = app.SelectedTickers;
            simulationStartDate = app.MarketSimulationStartDate;
            app.plotMarketData(marketDataPeriodToPlot,simulationStartDate);
        end

        % Clicked callback: InterestratestoshowListBox
        function InterestratestoshowListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Callback function
        function SelectFromMarketUniverseListBoxValueChanged(app, event)
            value = app.SelectFromMarketUniverseListBox.Value;

            app.SelectedTickers = value;
        end

        % Button pushed function: RunScenarioButton
        function RunScenarioButtonPushed(app, event)
            baseLifeTableFolder = app.InputArgs.Folders.inputFolderMortality;
            simulationStartDate = app.MarketSimulationStartDate;
            app.Scenario = generateMainScenarioMarketDataTimeSeries(app,simulationStartDate);
            [app.InvestingForIncomeSimulationResult,app.InvestingForIncomePostSimulationScenario] = runtimeclasses.runSimulation(app.Scenario,baseLifeTableFolder);

        end

        % Button pushed function: PlotResultsButton
        function PlotResultsButtonPushed(app, event)
                     
            marketDataPeriodToPlot = [app.MarketScenarioStartDate,app.MarketScenarioEndDate];
            simulationStartDate = app.MarketSimulationStartDate;
            app.plotPortfolioSimulationData(marketDataPeriodToPlot,simulationStartDate)

           %stackedPlot(app.InvestingForIncomeSimulationResult.Timetable)
        end

        % Button down function: InvestingforretirementincomeTab
        function InvestingforretirementincomeTabButtonDown(app, event)
           selectedTab = app.TabGroup.SelectedTab;
           tag = 'Plotportfolioevolutionincome';
           plotPanel = findobj(selectedTab.Children, 'Tag', tag);
           subPlotTypes = ["Surplus","Asset Values","Liabilities"];
           app.createSubplots(selectedTab,plotPanel,subPlotTypes); 
          
           %Display Market Index Account Parameters
           app.AccountNameEditField.Value = app.GlobalAppArgs.instruments.marketIndexAcctParameters.name;
           availableIndices = app.GlobalAppArgs.instruments.marketIndexAcctParameters.allowableIndexes;
           weights = app.GlobalAppArgs.instruments.marketIndexAcctParameters.targetWeights;
          
           marketIndexAcctTableData = table(availableIndices',weights');
           app.UITableMarketIndexAcct.Data =  marketIndexAcctTableData;

           % baseLifeTableFolder = app.InputArgs.Folders.inputFolderMortality;
           % simulationStartDate = app.MarketSimulationStartDate;
           % app.Scenario = generateMainScenarioMarketDataTimeSeries(app,simulationStartDate);
           % 
           %  [app.InvestingForIncomeSimulationResult,app.InvestingForIncomePostSimulationScenario] = runtimeclasses.runSimulation(app.Scenario,baseLifeTableFolder);
            % Find the panel by its Tag
           %subplottypes = ["Benchmark Portfolio","Traded Portfolio"];
           % subPlotTypes = app.InvestingForIncomeSimulationResult.TimeTable.Properties.VariableNames;
           % app.createSubplots(selectedTab,plotPanel,subPlotTypes); 
        end

        % Value changed function: SimulationStartDateDatePicker
        function SimulationStartDateDatePickerValueChanged(app, event)
            value = app.SimulationStartDateDatePicker.Value;
            value = value + app.ReferenceTime;
            value.TimeZone = app.TimeZone;
            app.MarketSimulationStartDate = value;

        end

        % Display data changed function: UITableMarketReferenceSimData
        function UITableMarketReferenceSimDataDisplayDataChanged(app, event)
            newDisplayData = app.UITableMarketReferenceSimData.DisplayData;            
            app.GlobalAppArgs.marketUniverse.benchmarkPortfolioWeights = newDisplayData.weights';
           app.GlobalAppArgs.marketUniverse.riskPremiums = newDisplayData.riskPremiums';
           
        end

        % Cell edit callback: UITableMarketReferenceSimData
        function UITableMarketReferenceSimDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % app.GlobalAppArgs.referencePortfolioParameters.benchmarkPortoflioWeights = newData.weights;
            % app.GlobalAppArgs.referencePortfolioParameters.riskPremiums = newData.riskPremiums;
        end

        % Value changed function: ESGModelListBox
        function ESGModelListBoxValueChanged(app, event)
            value = app.ESGModelListBox.Value;

            app.GlobalAppArgs.marketUniverse.ESGModelType = value;
            
        end

        % Value changed function: targetContributionsSlider
        function targetContributionsSliderValueChanged(app, event)
            value = app.targetContributionsSlider.Value;
            app.GlobalAppArgs.person.contribution = value*1000;
        end

        % Value changed function: YearsEditField
        function YearsEditFieldValueChanged(app, event)
            value = app.YearsEditField.Value;
            app.GlobalAppArgs.person.contributionPeriod = value;
        end

        % Value changed function: AnnuityTypeListBox
        function AnnuityTypeListBoxValueChanged(app, event)
            value = app.AnnuityTypeListBox.Value;
            app.GlobalAppArgs.hedgeAnnuity.annuityType = value;
            
        end

        % Value changed function: HedgeAnnuityStartDateDatePicker
        function HedgeAnnuityStartDateDatePickerValueChanged(app, event)
            annuityStartDate = app.HedgeAnnuityStartDateDatePicker.Value;
            annuityStartDate = annuityStartDate + app.ReferenceTime;
            annuityStartDate.TimeZone = app.TimeZone;
            app.GlobalAppArgs.hedgeAnnuity.annuityStartDate = annuityStartDate;
        end

        % Value changed function: IncomedeferredyrsEditField
        function IncomedeferredyrsEditFieldValueChanged(app, event)
            value = app.IncomedeferredyrsEditField.Value;
            app.GlobalAppArgs.hedgeAnnuity.GuaranteedIncomeDeferement = value;
        end

        % Value changed function: GuaranteedincreaserateEditField
        function GuaranteedincreaserateEditFieldValueChanged(app, event)
            value = app.GuaranteedincreaserateEditField.Value;
            app.GlobalAppArgs.hedgeAnnuity.GuaranteedPaymentIncreaseRate = value;
        end

        % Display data changed function: UITableMarketIndexAcct
        function UITableMarketIndexAcctDisplayDataChanged(app, event)
            newDisplayData = app.UITableMarketIndexAcct.DisplayData;
            app.GlobalAppArgs.marketIndexAcctParameters.targetWeights = newDisplayData.weights';
            
        end

        % Value changed function: AccountNameEditField
        function AccountNameEditFieldValueChanged(app, event)
            value = app.AccountNameEditField.Value;
            app.GlobalAppArgs.instruments.marketIndexAcctParameters.name = value;

        end

        % Value changed function: MaxnumberofpaymentsEditField
        function MaxnumberofpaymentsEditFieldValueChanged(app, event)
            value = app.MaxnumberofpaymentsEditField.Value;
            app.GlobalAppArgs.hedgeAnnuity.maxNumPayments = value;
        end

        % Value changed function: StrategyListBox
        function StrategyListBoxValueChanged(app, event)
            [allStrategyTypes, allStrategies] = enumeration('TradingStrategyType');
            selectedStrategyNames = app.StrategyListBox.Value;
            selectedIndices = find(ismember(allStrategies, selectedStrategyNames));            
            selectedStrategies = allStrategyTypes(selectedIndices);
            app.GlobalAppArgs.portfolioParameters.tradingStrategyTypes = num2cell(selectedStrategies);
                      
        end

        % Value changed function: IncomeToAnnuitiseEditField
        function IncomeToAnnuitiseEditFieldValueChanged(app, event)
            value = app.IncomeToAnnuitiseEditField.Value;
             app.GlobalAppArgs.hedgeAnnuity.guaranteedPayment = value;
             app.Person.CashflowStrategy.AnnualAmount = value;
        end

        % Value changed function: GuaranteedincreaserateEditField_2
        function GuaranteedincreaserateEditField_2ValueChanged(app, event)
            value = app.GuaranteedincreaserateEditField_2.Value;
            app.GlobalAppArgs.person.inflationRateAssumption = value;
            app.Person.CashflowStrategy.InflationRate = value;
        end

        % Value changed function: IncomedeferredyrsEditField_2
        function IncomedeferredyrsEditField_2ValueChanged(app, event)
            value = app.IncomedeferredyrsEditField_2.Value;
            app.GlobalAppArgs.person.deferment = value;
            app.Person.IncomeDeferement = value;
        end

        % Value changed function: AnnuityTermEditField
        function AnnuityTermEditFieldValueChanged(app, event)
            value = app.AnnuityTermEditField.Value;
            app.GlobalAppArgs.person.maxNumPayments = value;
            app.Person.CashflowStrategy.MaxNumPayments = value+1;
        end

        % Value changed function: AnnuityIncomeEditField
        function AnnuityIncomeEditFieldValueChanged(app, event)
            value = app.AnnuityIncomeEditField.Value;
            app.GlobalAppArgs.person.ownerPayment = value;
            app.Person.CashflowStrategy.AnnualAmount = value;
        end

        % Value changed function: InitialPortfolioValue
        function InitialPortfolioValueValueChanged(app, event)
            value = app.InitialPortfolioValue.Value;
            app.GlobalAppArgs.person.initialValue = value;
            app.Person.InitialValue = value;           
        end

        % Value changed function: PlanningHorizon
        function PlanningHorizonValueChanged(app, event)
            value = app.PlanningHorizon.Value;
            app.PlanningHorizon.Value = value;
            app.MarketScenarioEndDate = app.MarketScenarioStartDate + years(value);
            app.GlobalAppArgs.Dates.endDateScenario = app.MarketScenarioEndDate;
            app.GlobalAppArgs.Dates.endDates = app.MarketScenarioEndDate;
           
        end

        % Value changed function: TargetIncome
        function TargetIncomeValueChanged(app, event)
            value = app.TargetIncome.Value;
            app.GlobalAppArgs.person.ownerPayment = value;
            app.Person.CashflowStrategy.AnnualAmount = value;
        end

        % Value changed function: SamplevaluationageEditField
        function SamplevaluationageEditFieldValueChanged(app, event)
            value = app.SamplevaluationageEditField.Value;
            app.GlobalAppArgs.person.startAge = value;
            app.Person.Age = value;
            
        end

        % Button pushed function: CalulateannuityvalueButton
        function CalulateannuityvalueButtonPushed(app, event)
            
            annuityValues = struct();
            % Get all annuity types
            annuityTypes = enumeration('AnnuityType');


            localPerson = app.Person;
            frequency = localPerson.CashflowStrategy.Frequency; %utilities.FrequencyType.Annually;
            annuityIncome= localPerson.CashflowStrategy.AnnualAmount; %100000;
            annuityIncomeGtdIncrease = localPerson.CashflowStrategy.InflationRate/100; %0.03;
            baseInflationRate = annuityIncomeGtdIncrease;
            annuityStartDate  = localPerson.CashflowStrategy.StartDate;
            sampleAge = localPerson.Age;
            defermentPeriod = localPerson.IncomeDeferement;
            maxNumPmts = localPerson.CashflowStrategy.MaxNumPayments ;
            localMortalityDataSource = localPerson.CashflowStrategy.MortalityDataSource;
            localMortalityTableName = localPerson.CashflowStrategy.TableName;

            localCashflowStrategy = CashflowStrategy('AnnualAmount',annuityIncome,'StartDate',...
                annuityStartDate ,'Frequency',frequency,...
                'InflationRate',annuityIncomeGtdIncrease,'TableName',localMortalityTableName,'MortalityDataSource', localMortalityDataSource);


            %localPerson.CashflowStrategy = localCashflowStrategy;
            localPerson = Person('Gender',localPerson.Gender,'Age',sampleAge,'Country',localPerson.Country,'InitialValue',localPerson.InitialValue,...
                'TargetIncome',annuityIncome,'IncomeDeferement',defermentPeriod,'CashflowStrategy',localCashflowStrategy);


            baseLevelInterestRate = app.GlobalAppArgs.marketData.baseLevelInterestRate;
            levelRate = baseLevelInterestRate;
            %set up a ratecurve object
            type = 'zero';
            settle = annuityStartDate; %valuationDates(1);
            dates = calyears([1,3,5,10,20,40]);
            compounding = -1;
            basis = 0;
            rates = ones(1,length(dates))*levelRate;
            rateCurve = marketdata.RateCurveKaparra(type, settle, dates, rates, compounding, basis);


            annuityPaymentFrequency= frequency; %utilities.FrequencyType.Annually;

            dateLastAnnuityPayment = annuityStartDate + calyears(defermentPeriod + maxNumPmts);
            dateFirstAnnuityPayment = annuityStartDate + years(defermentPeriod);

            annuityPaymentDates = utilities.generateDateArrays(dateFirstAnnuityPayment, dateLastAnnuityPayment,annuityPaymentFrequency);
            for k = 1:length(annuityTypes)
                annuityType = annuityTypes(k);
                annuityFactory = AnnuityStrategyFactory.createAnnuityStrategyFactory(annuityType);
                annuity = annuityFactory.createInstrument(localPerson, annuityIncome,annuityIncomeGtdIncrease,annuityStartDate,defermentPeriod,maxNumPmts,annuityPaymentFrequency,annuityPaymentDates);

                annuityValues(k).Data = annuity.presentValue(rateCurve,baseInflationRate, annuityStartDate);
                annuityValues(k).AnnuityType = char(annuityType);
            end
            annuityValuesTable = struct2table(annuityValues);
            targetType =    'SingleLifeTimeAnnuity';
            index = strcmp(annuityValuesTable.AnnuityType,targetType);
            app.SingleLifeTimeEditField.Value = annuityValuesTable.Data(index);
            targetType =    'FixedAnnuity';
            index = strcmp(annuityValuesTable.AnnuityType,targetType);
            app.FixedAnnuityEditField.Value = annuityValuesTable.Data(index);
        end

        % Value changed function: SamplevaluationrateEditField
        function SamplevaluationrateEditFieldValueChanged(app, event)
            value = app.SamplevaluationrateEditField.Value;

            app.GlobalAppArgs.marketData.baseLevelInterestRate = value/100;
            
        end

        % Value changed function: MortalityTableListBox
        function MortalityTableListBoxValueChanged(app, event)
            value = app.MortalityTableListBox.Value;
 [m,s] = enumeration('TableNames');
            
            selectedIndice = find(ismember(m,app.MortalityTableListBox.Value));
            mortalityTableName = m(selectedIndice);

            app.GlobalAppArgs.Mortality.mortalityTableName = mortalityTableName;

            app.Person.CashflowStrategy.TableName = mortalityTableName;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YouandyourlifeMenu = uimenu(app.UIFigure);
            app.YouandyourlifeMenu.Text = 'You and your life';
            app.TestitemMenu = uimenu(app.YouandyourlifeMenu);
            app.TestitemMenu.Text = 'Test item';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = 'Menu';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'your_logo.png');
            app.PushTool2 = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.YouandyourlifeTab = uitab(app.TabGroup);
            app.YouandyourlifeTab.Title = 'You and your life';
            app.GridLayout2 = uigridlayout(app.YouandyourlifeTab);
            app.MyfinancialInfoPanel = uipanel(app.GridLayout2);
            app.MyfinancialInfoPanel.Title = 'My financial Info';
            app.MytargetIncomekpaLabel = uilabel(app.MyfinancialInfoPanel);
            app.MytargetIncomekpaLabel.Text = {'My target lifetime Income'; '$k pa'; 'Todays Dollars'};
            app.targetIncomeSlider = uislider(app.MyfinancialInfoPanel);
            app.targetIncomeSlider.Limits = [0 500];
            app.targetIncomeSlider.MajorTicks = [0 100 200 300 400 500];
            app.targetIncomeSlider.ValueChangedFcn = createCallbackFcn(app, @targetIncomeSliderValueChanged, true);
            app.MycurrentinvestibleassetsmSliderLabel = uilabel(app.MyfinancialInfoPanel);
            app.MycurrentinvestibleassetsmSliderLabel.Text = {'My current investible assets'; '$m'};
            app.investibleAssetsSlider = uislider(app.MyfinancialInfoPanel);
            app.investibleAssetsSlider.Limits = [0 5];
            app.investibleAssetsSlider.MajorTicks = [0 1 2 3 4 5];
            app.investibleAssetsSlider.ValueChangedFcn = createCallbackFcn(app, @investibleAssetsSliderValueChanged, true);
            app.IwanttoreceiveincomeKnobLabel = uilabel(app.MyfinancialInfoPanel);
            app.IwanttoreceiveincomeKnobLabel.Text = 'I want to receive income ';
            app.incomeFrequencyKnob = uiknob(app.MyfinancialInfoPanel, 'discrete');
            app.incomeFrequencyKnob.Items = {'Yearly', 'Quarterly', 'Monthly', 'Weekly'};
            app.incomeFrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @incomeFrequencyKnobValueChanged, true);
            app.incomeFrequencyKnob.Value = 'Yearly';
            app.IexpectmylifetimeincomewillneedtoincreasebyxpaSliderLabel = uilabel(app.MyfinancialInfoPanel);
            app.IexpectmylifetimeincomewillneedtoincreasebyxpaSliderLabel.Text = 'I expect my lifetime income will need to increase by x%pa';
            app.incomeincreaseSlider = uislider(app.MyfinancialInfoPanel);
            app.incomeincreaseSlider.Limits = [0 10];
            app.incomeincreaseSlider.ValueChangedFcn = createCallbackFcn(app, @incomeincreaseSliderValueChanged, true);
            app.incomeincreaseSlider.Value = 3;
            app.MytargetcontributionskpaTodaysDollarsSliderLabel = uilabel(app.MyfinancialInfoPanel);
            app.MytargetcontributionskpaTodaysDollarsSliderLabel.Text = {'My target contributions'; '$k pa'; 'Todays Dollars'};
            app.targetContributionsSlider = uislider(app.MyfinancialInfoPanel);
            app.targetContributionsSlider.Limits = [0 500];
            app.targetContributionsSlider.MajorTicks = [0 100 200 300 400 500];
            app.targetContributionsSlider.ValueChangedFcn = createCallbackFcn(app, @targetContributionsSliderValueChanged, true);
            app.IexpectmycontributionswillincreasebyxpaSliderLabel = uilabel(app.MyfinancialInfoPanel);
            app.IexpectmycontributionswillincreasebyxpaSliderLabel.Text = 'I expect mycontributions  will  increase by x%pa';
            app.contributionsIncreaseSlider = uislider(app.MyfinancialInfoPanel);
            app.contributionsIncreaseSlider.Limits = [0 10];
            app.contributionsIncreaseSlider.Value = 3;
            app.IwillcontributeforPanel = uipanel(app.MyfinancialInfoPanel);
            app.IwillcontributeforPanel.Title = 'I will contribute during this period::';
            app.YearsEditFieldLabel = uilabel(app.IwillcontributeforPanel);
            app.YearsEditFieldLabel.Text = 'Years';
            app.YearsEditField = uieditfield(app.IwillcontributeforPanel, 'numeric');
            app.YearsEditField.ValueChangedFcn = createCallbackFcn(app, @YearsEditFieldValueChanged, true);
            app.IwantmylifetimeincometocommencefromPanel = uipanel(app.MyfinancialInfoPanel);
            app.IwantmylifetimeincometocommencefromPanel.Title = 'I want my lifetime income to commence from:';
            app.FromDatePicker_2Label = uilabel(app.IwantmylifetimeincometocommencefromPanel);
            app.FromDatePicker_2Label.Text = 'From:';
            app.FromDatePicker_2 = uidatepicker(app.IwantmylifetimeincometocommencefromPanel);
            app.IwanttostarttheanalysisPanel = uipanel(app.MyfinancialInfoPanel);
            app.IwanttostarttheanalysisPanel.Title = 'I want to start the analysis ';
            app.FromDatePicker_3Label = uilabel(app.IwanttostarttheanalysisPanel);
            app.FromDatePicker_3Label.Text = 'From:';
            app.FromDatePicker_3 = uidatepicker(app.IwanttostarttheanalysisPanel);
            app.MypersonalinfoPanel = uipanel(app.GridLayout2);
            app.MypersonalinfoPanel.Title = 'My personal info';
            app.AgelastbirthdayEditFieldLabel = uilabel(app.MypersonalinfoPanel);
            app.AgelastbirthdayEditFieldLabel.Text = 'Age last birthday';
            app.AgeInputField = uieditfield(app.MypersonalinfoPanel, 'numeric');
            app.ThecountryIliveinEditFieldLabel = uilabel(app.MypersonalinfoPanel);
            app.ThecountryIliveinEditFieldLabel.Text = 'The country I live in ';
            app.CountryInputField = uieditfield(app.MypersonalinfoPanel, 'text');
            app.CountryInputField.Placeholder = 'Australia';
            app.MygenderisButtonGroup = uibuttongroup(app.MypersonalinfoPanel);
            app.MygenderisButtonGroup.Title = 'My gender is:';
            app.FemaleButton = uiradiobutton(app.MygenderisButtonGroup);
            app.FemaleButton.Text = 'Female';
            app.FemaleButton.Value = true;
            app.MaleButton = uiradiobutton(app.MygenderisButtonGroup);
            app.MaleButton.Text = 'Male';
            app.OtherGenderButton = uiradiobutton(app.MygenderisButtonGroup);
            app.OtherGenderButton.Text = 'Other';
            app.SampleInterestratesTab = uitab(app.TabGroup);
            app.SampleInterestratesTab.Title = 'Sample Interest rates ';
            app.SampleInterestratesTab.ButtonDownFcn = createCallbackFcn(app, @SampleInterestratesTabButtonDown, true);
            app.SelectratecurvestoloadPanel = uipanel(app.SampleInterestratesTab);
            app.SelectratecurvestoloadPanel.Title = 'Select rate curves to load';
            app.SampleDatesKnobLabel = uilabel(app.SelectratecurvestoloadPanel);
            app.SampleDatesKnobLabel.Text = 'Sample Dates';
            app.SampleDatesKnob = uiknob(app.SelectratecurvestoloadPanel, 'discrete');
            app.SampleDatesKnob.Items = {'Date 1', 'Date 2', 'Date 3', 'Date 4'};
            app.SampleDatesKnob.ValueChangedFcn = createCallbackFcn(app, @SampleDatesKnobValueChanged, true);
            app.SampleDatesKnob.Value = 'Date 1';
            app.SelectaratefileButton = uibutton(app.SelectratecurvestoloadPanel, 'push');
            app.SelectaratefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectaratefileButtonPushed, true);
            app.SelectaratefileButton.Text = 'Select a rate file';
            app.LoadselectedratefileButton = uibutton(app.SelectratecurvestoloadPanel, 'push');
            app.LoadselectedratefileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadselectedratefileButtonPushed, true);
            app.LoadselectedratefileButton.Text = 'Load selected rate file';
            app.SelectadateLabel = uilabel(app.SelectratecurvestoloadPanel);
            app.SelectadateLabel.Text = 'Select a date';
            app.SelectadateDatePicker = uidatepicker(app.SelectratecurvestoloadPanel);
            app.SelectadateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SelectadateDatePickerValueChanged, true);
            app.SelectadateDatePicker.Placeholder = '30/09/2023';
            app.SelectadateDatePicker.Value = datetime([2023 9 30]);
            app.SelectcountriestodisplayPanel = uipanel(app.SampleInterestratesTab);
            app.SelectcountriestodisplayPanel.Title = 'Select countries to display';
            app.Country1DropDownLabel = uilabel(app.SelectcountriestodisplayPanel);
            app.Country1DropDownLabel.Text = 'Country 1';
            app.Country1DropDown = uidropdown(app.SelectcountriestodisplayPanel);
            app.Country1DropDown.Items = {'''AU'''};
            app.Country1DropDown.Placeholder = '''AU''';
            app.Country1DropDown.Value = '''AU''';
            app.Country2DropDownLabel = uilabel(app.SelectcountriestodisplayPanel);
            app.Country2DropDownLabel.Text = 'Country 2';
            app.Country2DropDown = uidropdown(app.SelectcountriestodisplayPanel);
            app.Country2DropDown.Items = {'''NZ'''};
            app.Country2DropDown.Placeholder = '''NZ''';
            app.Country2DropDown.Value = '''NZ''';
            app.RatecurvesfortwocountriesPanel = uipanel(app.SampleInterestratesTab);
            app.RatecurvesfortwocountriesPanel.Title = 'Rate curves for two countries';
            app.UIAxes3 = uiaxes(app.RatecurvesfortwocountriesPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RatecurvesfortwocountriesPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PlotratesforselectedfileButton = uibutton(app.RatecurvesfortwocountriesPanel, 'push');
            app.PlotratesforselectedfileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotratesforselectedfileButtonPushed, true);
            app.PlotratesforselectedfileButton.Text = 'Plot rates for selected file';
            app.OverlayanotherdateButton = uibutton(app.RatecurvesfortwocountriesPanel, 'push');
            app.OverlayanotherdateButton.ButtonPushedFcn = createCallbackFcn(app, @OverlayanotherdateButtonPushed, true);
            app.OverlayanotherdateButton.Text = 'Overlay another date';
            app.MortalityandAnnuitiesTab = uitab(app.TabGroup);
            app.MortalityandAnnuitiesTab.Title = 'Mortality and Annuities';
            app.MortalityandAnnuitiesTab.ButtonDownFcn = createCallbackFcn(app, @MortalityandAnnuitiesTabButtonDown, true);
            app.SamplevaluationassumptionsPanel = uipanel(app.MortalityandAnnuitiesTab);
            app.SamplevaluationassumptionsPanel.Title = 'Sample valuation assumptions';
            app.SamplevaluationrateEditFieldLabel = uilabel(app.SamplevaluationassumptionsPanel);
            app.SamplevaluationrateEditFieldLabel.Text = 'Sample valuation rate';
            app.SamplevaluationrateEditField = uieditfield(app.SamplevaluationassumptionsPanel, 'numeric');
            app.SamplevaluationrateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplevaluationrateEditFieldValueChanged, true);
            app.MortalityTableListBoxLabel = uilabel(app.SamplevaluationassumptionsPanel);
            app.MortalityTableListBoxLabel.Text = 'Mortality Table';
            app.MortalityTableListBox = uilistbox(app.SamplevaluationassumptionsPanel);
            app.MortalityTableListBox.ValueChangedFcn = createCallbackFcn(app, @MortalityTableListBoxValueChanged, true);
            app.MortalityimprovementListBoxLabel = uilabel(app.SamplevaluationassumptionsPanel);
            app.MortalityimprovementListBoxLabel.Text = 'Mortality improvement ';
            app.MortalityimprovementListBox = uilistbox(app.SamplevaluationassumptionsPanel);
            app.SamplevaluationageEditFieldLabel = uilabel(app.SamplevaluationassumptionsPanel);
            app.SamplevaluationageEditFieldLabel.Text = 'Sample valuation age';
            app.SamplevaluationageEditField = uieditfield(app.SamplevaluationassumptionsPanel, 'numeric');
            app.SamplevaluationageEditField.ValueDisplayFormat = '%.0f';
            app.SamplevaluationageEditField.ValueChangedFcn = createCallbackFcn(app, @SamplevaluationageEditFieldValueChanged, true);
            app.PlotofannuityvaluesversusmaximumyearsPanel = uipanel(app.MortalityandAnnuitiesTab);
            app.PlotofannuityvaluesversusmaximumyearsPanel.Title = 'Plot of annuity values versus maximum years';
            app.PlotofannuityvaluesversusmaximumyearsPanel.Tag = 'PlotofannuityvaluesversusmaximumyearsPanel';
            app.AnnuityvaluesensitivityparametersPanel = uipanel(app.MortalityandAnnuitiesTab);
            app.AnnuityvaluesensitivityparametersPanel.Title = 'Annuity value sensitivity parameters';
            app.UISampleAnnuityValuesTable = uitable(app.AnnuityvaluesensitivityparametersPanel);
            app.UISampleAnnuityValuesTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UISampleAnnuityValuesTable.RowName = {};
            app.XAxisVariableLabel = uilabel(app.AnnuityvaluesensitivityparametersPanel);
            app.XAxisVariableLabel.Text = 'Line Plot Variable';
            app.XAxisToPlotListBox = uilistbox(app.AnnuityvaluesensitivityparametersPanel);
            app.LineToPlotListBox = uilistbox(app.AnnuityvaluesensitivityparametersPanel);
            app.InputVariablesForTableListBoxLabel = uilabel(app.AnnuityvaluesensitivityparametersPanel);
            app.InputVariablesForTableListBoxLabel.Text = 'Input Variables For Table';
            app.InputVariablesForTableListBox = uilistbox(app.AnnuityvaluesensitivityparametersPanel);
            app.InputVariablesForTableListBox.Multiselect = 'on';
            app.InputVariablesForTableListBox.Value = {'Item 1'};
            app.XAxisVariableLabel_2 = uilabel(app.AnnuityvaluesensitivityparametersPanel);
            app.XAxisVariableLabel_2.Text = 'X-Axis Plot variable';
            app.AnnuityTypeForTableLabel = uilabel(app.AnnuityvaluesensitivityparametersPanel);
            app.AnnuityTypeForTableLabel.Text = 'Annuity Type For Table';
            app.AnnuityTypeToDisplay = uilistbox(app.AnnuityvaluesensitivityparametersPanel);
            app.AnnuitysensitivityruncontrolPanel = uipanel(app.MortalityandAnnuitiesTab);
            app.AnnuitysensitivityruncontrolPanel.Title = 'Annuity sensitivity run control';
            app.PopulateAnnuityValuesTableButton = uibutton(app.AnnuitysensitivityruncontrolPanel, 'push');
            app.PopulateAnnuityValuesTableButton.ButtonPushedFcn = createCallbackFcn(app, @PopulateAnnuityValuesTableButtonPushed, true);
            app.PopulateAnnuityValuesTableButton.Text = 'Populate Annuity Values Table';
            app.PlotannuityvaluesButton = uibutton(app.AnnuitysensitivityruncontrolPanel, 'push');
            app.PlotannuityvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotannuityvaluesButtonPushed, true);
            app.PlotannuityvaluesButton.Text = 'Plot annuity values';
            app.SampleannuitypaymentcharacteristicsPanel = uipanel(app.MortalityandAnnuitiesTab);
            app.SampleannuitypaymentcharacteristicsPanel.Title = 'Sample annuity payment characteristics';
            app.AnnuityTermEditFieldLabel = uilabel(app.SampleannuitypaymentcharacteristicsPanel);
            app.AnnuityTermEditFieldLabel.Text = 'Annuity Term';
            app.AnnuityTermEditField = uieditfield(app.SampleannuitypaymentcharacteristicsPanel, 'numeric');
            app.AnnuityTermEditField.ValueChangedFcn = createCallbackFcn(app, @AnnuityTermEditFieldValueChanged, true);
            app.IncomedeferredyrsEditField_2Label = uilabel(app.SampleannuitypaymentcharacteristicsPanel);
            app.IncomedeferredyrsEditField_2Label.Text = 'Income deferred (yrs)';
            app.IncomedeferredyrsEditField_2 = uieditfield(app.SampleannuitypaymentcharacteristicsPanel, 'numeric');
            app.IncomedeferredyrsEditField_2.ValueChangedFcn = createCallbackFcn(app, @IncomedeferredyrsEditField_2ValueChanged, true);
            app.GuaranteedincreaserateEditField_2Label = uilabel(app.SampleannuitypaymentcharacteristicsPanel);
            app.GuaranteedincreaserateEditField_2Label.Text = 'Guaranteed increase rate';
            app.GuaranteedincreaserateEditField_2 = uieditfield(app.SampleannuitypaymentcharacteristicsPanel, 'numeric');
            app.GuaranteedincreaserateEditField_2.ValueChangedFcn = createCallbackFcn(app, @GuaranteedincreaserateEditField_2ValueChanged, true);
            app.AnnuityIncomeEditFieldLabel = uilabel(app.SampleannuitypaymentcharacteristicsPanel);
            app.AnnuityIncomeEditFieldLabel.Text = 'Annuity Income';
            app.AnnuityIncomeEditField = uieditfield(app.SampleannuitypaymentcharacteristicsPanel, 'numeric');
            app.AnnuityIncomeEditField.ValueChangedFcn = createCallbackFcn(app, @AnnuityIncomeEditFieldValueChanged, true);
            app.SampleAnnuityValuePanel = uipanel(app.MortalityandAnnuitiesTab);
            app.SampleAnnuityValuePanel.Title = 'Sample Annuity Value';
            app.SingleLifeTimeEditFieldLabel = uilabel(app.SampleAnnuityValuePanel);
            app.SingleLifeTimeEditFieldLabel.Text = 'Single Life Time';
            app.SingleLifeTimeEditField = uieditfield(app.SampleAnnuityValuePanel, 'numeric');
            app.FixedAnnuityEditFieldLabel = uilabel(app.SampleAnnuityValuePanel);
            app.FixedAnnuityEditFieldLabel.Text = 'Fixed Annuity';
            app.FixedAnnuityEditField = uieditfield(app.SampleAnnuityValuePanel, 'numeric');
            app.CalulateannuityvalueButton = uibutton(app.SampleAnnuityValuePanel, 'push');
            app.CalulateannuityvalueButton.ButtonPushedFcn = createCallbackFcn(app, @CalulateannuityvalueButtonPushed, true);
            app.CalulateannuityvalueButton.Text = 'Calulate annuity value';
            app.FinancialmarketsTab = uitab(app.TabGroup);
            app.FinancialmarketsTab.Title = 'Financial markets';
            app.FinancialmarketsTab.ButtonDownFcn = createCallbackFcn(app, @FinancialmarketsTabButtonDown, true);
            app.SPanel = uipanel(app.FinancialmarketsTab);
            app.SPanel.Title = 'S';
            app.Tree = uitree(app.SPanel, 'checkbox');
            app.LoadallNode = uitreenode(app.Tree);
            app.LoadallNode.Text = 'Load all';
            app.InterestratesNode = uitreenode(app.LoadallNode);
            app.InterestratesNode.Text = 'Interest rates';
            app.EquityindexesNode = uitreenode(app.LoadallNode);
            app.EquityindexesNode.Text = 'Equity indexes';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.InterestratesNode, app.EquityindexesNode, app.LoadallNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.LoadMarketDataButton = uibutton(app.SPanel, 'push');
            app.LoadMarketDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMarketDataButtonPushed, true);
            app.LoadMarketDataButton.Text = 'Load Market Data';
            app.PlotMarketDataButton = uibutton(app.SPanel, 'push');
            app.PlotMarketDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMarketDataButtonPushed, true);
            app.PlotMarketDataButton.Text = 'Plot Market Data';
            app.InterestratestoshowListBoxLabel = uilabel(app.SPanel);
            app.InterestratestoshowListBoxLabel.Text = 'Interest rates to show';
            app.InterestratestoshowListBox = uilistbox(app.SPanel);
            app.InterestratestoshowListBox.Items = {'AU', 'NZ', 'USD', 'EU', 'GB'};
            app.InterestratestoshowListBox.ClickedFcn = createCallbackFcn(app, @InterestratestoshowListBoxClicked, true);
            app.InterestratestoshowListBox.Value = 'AU';
            app.DisplaypricesorreturnsButtonGroup = uibuttongroup(app.SPanel);
            app.DisplaypricesorreturnsButtonGroup.Title = 'Display prices or returns';
            app.PricesButton = uitogglebutton(app.DisplaypricesorreturnsButtonGroup);
            app.PricesButton.Text = 'Prices';
            app.PricesButton.Value = true;
            app.ReturnsButton = uitogglebutton(app.DisplaypricesorreturnsButtonGroup);
            app.ReturnsButton.Text = 'Returns';
            app.BothButton = uitogglebutton(app.DisplaypricesorreturnsButtonGroup);
            app.BothButton.Text = 'Both';
            app.DisplaymarketdataforperiodPanel = uipanel(app.SPanel);
            app.DisplaymarketdataforperiodPanel.Title = 'Display market data for period';
            app.ToDatePickerLabel = uilabel(app.DisplaymarketdataforperiodPanel);
            app.ToDatePickerLabel.Text = 'To';
            app.MarketScenarioToDatePicker = uidatepicker(app.DisplaymarketdataforperiodPanel);
            app.MarketScenarioToDatePicker.ValueChangedFcn = createCallbackFcn(app, @MarketScenarioToDatePickerValueChanged, true);
            app.MarketScenarioToDatePicker.Placeholder = 'app.endDateScenario';
            app.MarketScenarioToDatePicker.Value = datetime([2022 12 31]);
            app.FromDatePickerLabel = uilabel(app.DisplaymarketdataforperiodPanel);
            app.FromDatePickerLabel.Text = 'From';
            app.MarketScenarioFromDatePicker = uidatepicker(app.DisplaymarketdataforperiodPanel);
            app.MarketScenarioFromDatePicker.ValueChangedFcn = createCallbackFcn(app, @MarketScenarioFromDatePickerValueChanged, true);
            app.MarketScenarioFromDatePicker.Placeholder = 'app.ScenarioStartDate';
            app.MarketScenarioFromDatePicker.Value = datetime([2019 1 1]);
            app.SimulationStartDateDatePickerLabel = uilabel(app.SPanel);
            app.SimulationStartDateDatePickerLabel.Text = 'Simulation Start Date';
            app.SimulationStartDateDatePicker = uidatepicker(app.SPanel);
            app.SimulationStartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @SimulationStartDateDatePickerValueChanged, true);
            app.ESGModelListBoxLabel = uilabel(app.SPanel);
            app.ESGModelListBoxLabel.Text = 'ESG Model';
            app.ESGModelListBox = uilistbox(app.SPanel);
            app.ESGModelListBox.Items = {};
            app.ESGModelListBox.ValueChangedFcn = createCallbackFcn(app, @ESGModelListBoxValueChanged, true);
            app.ESGModelListBox.Value = {};
            app.Plotmarketdata = uipanel(app.FinancialmarketsTab);
            app.Plotmarketdata.Title = 'Plot market data';
            app.Plotmarketdata.Tag = 'PlotMarketData';
            app.UITableMarketReferenceSimData = uitable(app.FinancialmarketsTab);
            app.UITableMarketReferenceSimData.ColumnName = {'Instrument'; 'Target Portfolio Weight'; 'Risk Premium'};
            app.UITableMarketReferenceSimData.ColumnRearrangeable = 'on';
            app.UITableMarketReferenceSimData.RowName = {};
            app.UITableMarketReferenceSimData.ColumnSortable = [true false false false];
            app.UITableMarketReferenceSimData.ColumnEditable = [false true true false];
            app.UITableMarketReferenceSimData.CellEditCallback = createCallbackFcn(app, @UITableMarketReferenceSimDataCellEdit, true);
            app.UITableMarketReferenceSimData.DisplayDataChangedFcn = createCallbackFcn(app, @UITableMarketReferenceSimDataDisplayDataChanged, true);
            app.InvestingforretirementincomeTab = uitab(app.TabGroup);
            app.InvestingforretirementincomeTab.Title = 'Investing for retirement income';
            app.InvestingforretirementincomeTab.ButtonDownFcn = createCallbackFcn(app, @InvestingforretirementincomeTabButtonDown, true);
            app.InvestmentStrategyDefintiionPanel_3 = uipanel(app.InvestingforretirementincomeTab);
            app.InvestmentStrategyDefintiionPanel_3.Title = 'Investment Strategy Defintiion';
            app.InitialPortoflioValueLabel = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.InitialPortoflioValueLabel.Text = 'Initial Portoflio Value';
            app.InitialPortfolioValue = uieditfield(app.InvestmentStrategyDefintiionPanel_3, 'numeric');
            app.InitialPortfolioValue.ValueChangedFcn = createCallbackFcn(app, @InitialPortfolioValueValueChanged, true);
            app.InitialPortoflioValueLabel_3 = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.InitialPortoflioValueLabel_3.Text = 'Planning horizon (yrs)';
            app.PlanningHorizon = uieditfield(app.InvestmentStrategyDefintiionPanel_3, 'numeric');
            app.PlanningHorizon.ValueChangedFcn = createCallbackFcn(app, @PlanningHorizonValueChanged, true);
            app.InitialPortoflioValueLabel_4 = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.InitialPortoflioValueLabel_4.Text = 'Target income';
            app.TargetIncome = uieditfield(app.InvestmentStrategyDefintiionPanel_3, 'numeric');
            app.TargetIncome.ValueChangedFcn = createCallbackFcn(app, @TargetIncomeValueChanged, true);
            app.RiskconstraintsLabel_2 = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.RiskconstraintsLabel_2.Text = 'Risk constraints';
            app.InvestmentObjectiveLabel_3 = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.InvestmentObjectiveLabel_3.Text = 'Investment Objective';
            app.PortfoliostartdateDatePickerLabel = uilabel(app.InvestmentStrategyDefintiionPanel_3);
            app.PortfoliostartdateDatePickerLabel.Text = 'Portfolio start date';
            app.PortfoliostartdateDatePicker = uidatepicker(app.InvestmentStrategyDefintiionPanel_3);
            app.PortfolioevolutionvisualisationPanel = uipanel(app.InvestingforretirementincomeTab);
            app.PortfolioevolutionvisualisationPanel.Title = 'Portfolio evolution visualisation';
            app.PortfolioevolutionvisualisationPanel.Tag = 'Plotportfolioevolutionincome';
            app.SimulationcontrolPanel = uipanel(app.InvestingforretirementincomeTab);
            app.SimulationcontrolPanel.Title = 'Simulation control';
            app.RunScenarioButton = uibutton(app.SimulationcontrolPanel, 'push');
            app.RunScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @RunScenarioButtonPushed, true);
            app.RunScenarioButton.Text = 'Run Scenario';
            app.PlotResultsButton = uibutton(app.SimulationcontrolPanel, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButtonPushed, true);
            app.PlotResultsButton.Text = 'Plot Results';
            app.MainPortfolioTradingStrategyPanel = uipanel(app.InvestingforretirementincomeTab);
            app.MainPortfolioTradingStrategyPanel.Title = 'Main portfolio trading strategy';
            app.StrategyListBoxLabel = uilabel(app.MainPortfolioTradingStrategyPanel);
            app.StrategyListBoxLabel.Text = 'Strategy';
            app.StrategyListBox = uilistbox(app.MainPortfolioTradingStrategyPanel);
            app.StrategyListBox.Multiselect = 'on';
            app.StrategyListBox.ValueChangedFcn = createCallbackFcn(app, @StrategyListBoxValueChanged, true);
            app.StrategyListBox.Value = {'Item 1'};
            app.IncometoannuitiseEditFieldLabel = uilabel(app.MainPortfolioTradingStrategyPanel);
            app.IncometoannuitiseEditFieldLabel.Text = 'Income to annuitise';
            app.IncomeToAnnuitiseEditField = uieditfield(app.MainPortfolioTradingStrategyPanel, 'numeric');
            app.IncomeToAnnuitiseEditField.ValueChangedFcn = createCallbackFcn(app, @IncomeToAnnuitiseEditFieldValueChanged, true);
            app.TradingportoflioinstrumentsPanel = uipanel(app.InvestingforretirementincomeTab);
            app.TradingportoflioinstrumentsPanel.Title = 'Trading portoflio instruments';
            app.MarketIndexAccountParametersPanel = uipanel(app.TradingportoflioinstrumentsPanel);
            app.MarketIndexAccountParametersPanel.Title = 'Market Index Account Parameters';
            app.UITableMarketIndexAcct = uitable(app.MarketIndexAccountParametersPanel);
            app.UITableMarketIndexAcct.ColumnName = {'Instruments'; 'Weights'; ''};
            app.UITableMarketIndexAcct.RowName = {};
            app.UITableMarketIndexAcct.DisplayDataChangedFcn = createCallbackFcn(app, @UITableMarketIndexAcctDisplayDataChanged, true);
            app.AccountNameEditFieldLabel = uilabel(app.MarketIndexAccountParametersPanel);
            app.AccountNameEditFieldLabel.Text = 'Account Name';
            app.AccountNameEditField = uieditfield(app.MarketIndexAccountParametersPanel, 'text');
            app.AccountNameEditField.ValueChangedFcn = createCallbackFcn(app, @AccountNameEditFieldValueChanged, true);
            app.AnnuityTypePanel = uipanel(app.TradingportoflioinstrumentsPanel);
            app.AnnuityTypePanel.Title = 'Annuity Type';
            app.AnnuityTypeListBoxLabel = uilabel(app.AnnuityTypePanel);
            app.AnnuityTypeListBoxLabel.Text = 'Annuity Type';
            app.AnnuityTypeListBox = uilistbox(app.AnnuityTypePanel);
            app.AnnuityTypeListBox.Items = {'Item 1', 'Item 2'};
            app.AnnuityTypeListBox.ValueChangedFcn = createCallbackFcn(app, @AnnuityTypeListBoxValueChanged, true);
            app.StartDateDatePickerLabel = uilabel(app.AnnuityTypePanel);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.HedgeAnnuityStartDateDatePicker = uidatepicker(app.AnnuityTypePanel);
            app.HedgeAnnuityStartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @HedgeAnnuityStartDateDatePickerValueChanged, true);
            app.IncomedeferredyrsEditFieldLabel = uilabel(app.AnnuityTypePanel);
            app.IncomedeferredyrsEditFieldLabel.Text = 'Income deferred (yrs)';
            app.IncomedeferredyrsEditField = uieditfield(app.AnnuityTypePanel, 'numeric');
            app.IncomedeferredyrsEditField.ValueChangedFcn = createCallbackFcn(app, @IncomedeferredyrsEditFieldValueChanged, true);
            app.GuaranteedincreaserateEditFieldLabel = uilabel(app.AnnuityTypePanel);
            app.GuaranteedincreaserateEditFieldLabel.Text = 'Guaranteed increase rate';
            app.GuaranteedincreaserateEditField = uieditfield(app.AnnuityTypePanel, 'numeric');
            app.GuaranteedincreaserateEditField.ValueChangedFcn = createCallbackFcn(app, @GuaranteedincreaserateEditFieldValueChanged, true);
            app.MaxnumberofpaymentsEditFieldLabel = uilabel(app.AnnuityTypePanel);
            app.MaxnumberofpaymentsEditFieldLabel.Text = 'Max number of payments';
            app.MaxnumberofpaymentsEditField = uieditfield(app.AnnuityTypePanel, 'numeric');
            app.MaxnumberofpaymentsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxnumberofpaymentsEditFieldValueChanged, true);
            app.InvestingforaccumulationTab = uitab(app.TabGroup);
            app.InvestingforaccumulationTab.Title = 'Investing for accumulation';
            app.InvestmentStrategyDefintiionPanel = uipanel(app.InvestingforaccumulationTab);
            app.InvestmentStrategyDefintiionPanel.Title = 'Investment Strategy Defintiion';
            app.InvestmentObjectiveLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.InvestmentObjectiveLabel.Text = 'Investment Objective';
            app.AllowableInstrumentsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.AllowableInstrumentsLabel.Text = 'Allowable Instruments';
            app.MarketIndexAccountParametersLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.MarketIndexAccountParametersLabel.Text = 'Market Index Account Parameters';
            app.PlanningHorizonLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.PlanningHorizonLabel.Text = 'Planning Horizon';
            app.AnnuityinstrumentparametersLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.AnnuityinstrumentparametersLabel.Text = 'Annuity instrument parameters';
            app.ReferencePortfolioParametersLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.ReferencePortfolioParametersLabel.Text = 'Reference Portfolio Parameters';
            app.NameLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.NameLabel.Text = 'Name';
            app.InstrumentsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.InstrumentsLabel.Text = 'Instruments';
            app.TargetWeightsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.TargetWeightsLabel.Text = 'Target Weights';
            app.ExposureLimitsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.ExposureLimitsLabel.Text = 'Exposure Limits';
            app.TradingStrategyLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.TradingStrategyLabel.Text = 'Trading Strategy';
            app.AnnuitytobuyLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.AnnuitytobuyLabel.Text = 'Annuity to buy';
            app.PortfolioTradingStrategyLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.PortfolioTradingStrategyLabel.Text = 'Portfolio Trading Strategy';
            app.annuitystartdateLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.annuitystartdateLabel.Text = 'annuity start date';
            app.firstannuitypaymentdeferredbyLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.firstannuitypaymentdeferredbyLabel.Text = 'first annuity payment deferred by: ';
            app.GuaranteedinflationrateLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.GuaranteedinflationrateLabel.Text = 'Guaranteed inflation rate';
            app.MaximumnumberofpaymentsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.MaximumnumberofpaymentsLabel.Text = 'Maximum number of payments';
            app.ProportionofassettoinvestinannuityLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.ProportionofassettoinvestinannuityLabel.Text = 'Proportion of asset to invest in annuity';
            app.TypeofannuityLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.TypeofannuityLabel.Text = 'Type of annuity ';
            app.InitialportfoliovalueLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.InitialportfoliovalueLabel.Text = 'Initial portfolio value';
            app.AnnualcontributionLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.AnnualcontributionLabel.Text = 'Annual contribution';
            app.RiskconstraintsLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.RiskconstraintsLabel.Text = 'Risk constraints';
            app.ProportionofassettoinvestinmarketindexaccountLabel = uilabel(app.InvestmentStrategyDefintiionPanel);
            app.ProportionofassettoinvestinmarketindexaccountLabel.Text = 'Proportion of asset to invest in market index account';
            app.EfficientfrontierPanel = uipanel(app.InvestingforaccumulationTab);
            app.EfficientfrontierPanel.Title = 'Efficient frontier';
            app.InvestmentStatisticsPanel = uipanel(app.InvestingforaccumulationTab);
            app.InvestmentStatisticsPanel.Title = 'Investment Statistics';
            app.SimulationParametersPanel_2 = uipanel(app.InvestingforaccumulationTab);
            app.SimulationParametersPanel_2.Title = 'Simulation Parameters';
            app.Label = uilabel(app.SimulationParametersPanel_2);
            app.Label.Text = '';
            app.AssetValuationFrequencyLabel = uilabel(app.SimulationParametersPanel_2);
            app.AssetValuationFrequencyLabel.Text = 'Asset Valuation Frequency';
            app.MortalitytableLabel = uilabel(app.SimulationParametersPanel_2);
            app.MortalitytableLabel.Text = 'Mortality table';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvestingForRetirement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end